classdef Matlab_Value_Chains < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        Image33                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        IndoModValueChainsModelLabel    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        HowtousethemodelLabel           matlab.ui.control.Label
        OverviewofthemodelLabel         matlab.ui.control.Label
        aValuechainparametersTab        matlab.ui.container.Tab
        Image9_33                       matlab.ui.control.Image
        Farmerstickiness_Editfield      matlab.ui.control.NumericEditField
        BuyersannualdiscountrateEditField_2Label  matlab.ui.control.Label
        ConfirmLamp_2                   matlab.ui.control.Lamp
        StartnewscenarioButton          matlab.ui.control.Button
        Image9_29                       matlab.ui.control.Image
        Image9_27                       matlab.ui.control.Image
        Image9_26                       matlab.ui.control.Image
        Image9_25                       matlab.ui.control.Image
        Image27                         matlab.ui.control.Image
        Image26                         matlab.ui.control.Image
        ValuechaincreditsupportLabel    matlab.ui.control.Label
        PricesanddiscountratesLabel     matlab.ui.control.Label
        KeyparametersLabel              matlab.ui.control.Label
        Image25                         matlab.ui.control.Image
        ValuechainlandusecharacteristicsLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CreditlimitofferedbyvaluechainforoperationsmillionIDREditField  matlab.ui.control.NumericEditField
        CreditofferedbyvaluechainforoperationsmillionIDREditFieldLabel  matlab.ui.control.Label
        CreditofferedbyvaluechainforestablishmentmillionIDREditField  matlab.ui.control.NumericEditField
        CreditofferedbyvaluechainforestablishmentEditFieldLabel  matlab.ui.control.Label
        BuyersannualdiscountrateEditField  matlab.ui.control.NumericEditField
        BuyersannualdiscountrateEditFieldLabel  matlab.ui.control.Label
        NetpricereceivedbybuyerperunitofoutputmillionIDREditField  matlab.ui.control.NumericEditField
        NetpricereceivedbybuyerperunitofoutputmillionIDREditFieldLabel  matlab.ui.control.Label
        CarbonstockoflandusetChaEditField  matlab.ui.control.NumericEditField
        CarbonstockoflandusetChaEditFieldLabel  matlab.ui.control.Label
        RULSEerosioncovermanagementfactorEditField  matlab.ui.control.NumericEditField
        RULSEerosioncovermanagementfactorEditFieldLabel  matlab.ui.control.Label
        ValuechainnameEditField         matlab.ui.control.EditField
        ValuechainnameEditFieldLabel    matlab.ui.control.Label
        IntroduceValueChainSwitch       matlab.ui.control.Switch
        IntroduceValueChainSwitchLabel  matlab.ui.control.Label
        ValuechaincommodityDropDown     matlab.ui.control.DropDown
        ValuechaincommodityDropDownLabel  matlab.ui.control.Label
        bValuechainparametersTab        matlab.ui.container.Tab
        PostharvesthandlingEditField    matlab.ui.control.NumericEditField
        PostharvesthandlingEditFieldLabel  matlab.ui.control.Label
        HarvestingEditField             matlab.ui.control.NumericEditField
        HarvestingEditFieldLabel        matlab.ui.control.Label
        ChemicalapplicationsEditField   matlab.ui.control.NumericEditField
        ChemicalapplicationsEditFieldLabel  matlab.ui.control.Label
        FertiliserapplicationsEditField  matlab.ui.control.NumericEditField
        FertiliserapplicationsEditFieldLabel  matlab.ui.control.Label
        WeedingEditField                matlab.ui.control.NumericEditField
        WeedingEditFieldLabel           matlab.ui.control.Label
        PlantingEditField               matlab.ui.control.NumericEditField
        PlantingEditFieldLabel          matlab.ui.control.Label
        LandpreparationEditField        matlab.ui.control.NumericEditField
        LandpreparationEditFieldLabel   matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ValuechainlabourinputsLabel     matlab.ui.control.Label
        ConfirmLamp                     matlab.ui.control.Lamp
        ConfirmvaluechainparametersButton  matlab.ui.control.Button
        YieldsEditField                 matlab.ui.control.NumericEditField
        YieldsEditFieldLabel            matlab.ui.control.Label
        SeedseedlinginputsEditField     matlab.ui.control.NumericEditField
        SeedseedlinginputsEditFieldLabel  matlab.ui.control.Label
        ChemicalinputsEditField         matlab.ui.control.NumericEditField
        ChemicalinputsEditFieldLabel    matlab.ui.control.Label
        FertiliserinputsEditField       matlab.ui.control.NumericEditField
        FertiliserinputsEditFieldLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        ValuechainoutputsLabel          matlab.ui.control.Label
        ValuechaincapitalinputsLabel    matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        ValuechaintransactioncostsTab   matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        FarmerinvestmenttransactioncostsforvaluechainLabel  matlab.ui.control.Label
        BuyerinvestmenttransactioncostsforvaluechainLabel  matlab.ui.control.Label
        ConfirmtransactioncostsLamp     matlab.ui.control.Lamp
        ConfirmvaluechaintransactioncostsButton  matlab.ui.control.Button
        variableforfarmerEditField      matlab.ui.control.NumericEditField
        EditFieldLabel_16               matlab.ui.control.Label
        fixedannualcostforfarmerEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_15               matlab.ui.control.Label
        UpfrontinvestmentcostforfarmermillionIDREditField  matlab.ui.control.NumericEditField
        EditFieldLabel_14               matlab.ui.control.Label
        variableforbuyerEditField       matlab.ui.control.NumericEditField
        EditFieldLabel_13               matlab.ui.control.Label
        fixedannualplotforbuyerEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_12               matlab.ui.control.Label
        fixedannualfarmerforbuyerEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_11               matlab.ui.control.Label
        UpfrontinvestmentcostforbuyerpervillagemillionIDREditField  matlab.ui.control.NumericEditField
        UpfrontinvestmentcostforbuyerpervillagemillionIDREditFieldLabel  matlab.ui.control.Label
        ValuechaineligibilityTab        matlab.ui.container.Tab
        Image9_32                       matlab.ui.control.Image
        Image9_31                       matlab.ui.control.Image
        Image9_30                       matlab.ui.control.Image
        Image9_24                       matlab.ui.control.Image
        Image6_3                        matlab.ui.control.Image
        Image6_2                        matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5_2                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        HouseholdcharacteristicsLabel   matlab.ui.control.Label
        CurrentlandusesLabel            matlab.ui.control.Label
        PlotsoilconservationLabel       matlab.ui.control.Label
        PlottenureLabel                 matlab.ui.control.Label
        PlotslopeLabel                  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        eligibilityparametersconfirmedLamp  matlab.ui.control.Lamp
        ConfirmeligibilityparametersButton  matlab.ui.control.Button
        OnlyhouseholdswhoaresociallyconnectedCheckBox  matlab.ui.control.CheckBox
        OnlyrisktalkinghouseholdsCheckBox  matlab.ui.control.CheckBox
        MaximumdistancefrommarketinkmsEditField  matlab.ui.control.NumericEditField
        MaximumdistancefrommarketinkmsEditFieldLabel  matlab.ui.control.Label
        MinimumeducationinyearsEditField  matlab.ui.control.NumericEditField
        MinimumeducationinyearsLabel    matlab.ui.control.Label
        PlotscurrentlyplantedwithmixedagroforestryCheckBox  matlab.ui.control.CheckBox
        PlotscurrentlyplantedwithpaddyuplandsriceCheckBox  matlab.ui.control.CheckBox
        PlotscurrentlyplantedwithhorticultureCheckBox  matlab.ui.control.CheckBox
        PlotscurrentlyplantedwithcoffeeCheckBox  matlab.ui.control.CheckBox
        PlotscurrentlywithsoilconservationpracticesCheckBox  matlab.ui.control.CheckBox
        PlotscurrentlywithoutsoilconservationpracticesCheckBox  matlab.ui.control.CheckBox
        OwnermanagedplotsCheckBox       matlab.ui.control.CheckBox
        RentedplotsCheckBox             matlab.ui.control.CheckBox
        SteepslopedplotsCheckBox        matlab.ui.control.CheckBox
        ModeratelyslopedplotsCheckBox   matlab.ui.control.CheckBox
        FlatslopedplotsCheckBox         matlab.ui.control.CheckBox
        RunmodelTab                     matlab.ui.container.Tab
        Image9_23                       matlab.ui.control.Image
        Image9_22                       matlab.ui.control.Image
        Image9_21                       matlab.ui.control.Image
        Image9_20                       matlab.ui.control.Image
        Image9_19                       matlab.ui.control.Image
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        DatapreparedLamp                matlab.ui.control.Lamp
        PackagedataforanalysisButton    matlab.ui.control.Button
        DatapackagedLamp                matlab.ui.control.Lamp
        PackageresultsforexportButton   matlab.ui.control.Button
        RunpolicyluLamp                 matlab.ui.control.Lamp
        FarmerlandusedecisionswithvaluechainButton  matlab.ui.control.Button
        BaselinemodelcompleteLamp       matlab.ui.control.Lamp
        FarmerlandusedecisionswithoutvaluechainButton  matlab.ui.control.Button
        BuyermodelrunLamp               matlab.ui.control.Lamp
        ValuechainbuyerdecisionsButton  matlab.ui.control.Button
        SummaryofresultsTab             matlab.ui.container.Tab
        MinimumpricebeforetransactioncostsEditField  matlab.ui.control.EditField
        PricereceivedbybuyerrpperunitofoutputEditField_2Label  matlab.ui.control.Label
        SummaryofresultsLabel           matlab.ui.control.Label
        Image24                         matlab.ui.control.Image
        Image23                         matlab.ui.control.Image
        Image21                         matlab.ui.control.Image
        Image20                         matlab.ui.control.Image
        Image19                         matlab.ui.control.Image
        valuechainaveragenpvEditField   matlab.ui.control.EditField
        EditFieldLabel_23               matlab.ui.control.Label
        BaselineaveragenpvEditField     matlab.ui.control.EditField
        EditFieldLabel_22               matlab.ui.control.Label
        valuechainaveragecarbonnagriculturalplotsthhyrEditField  matlab.ui.control.EditField
        EditFieldLabel_21               matlab.ui.control.Label
        BaselineaveragecarbonbiomassonagriculturalplotstCo2EditField  matlab.ui.control.EditField
        EditFieldLabel_20               matlab.ui.control.Label
        valuechainaveragesoilerosiononagriculturalplotsthhyrEditField  matlab.ui.control.EditField
        EditFieldLabel_19               matlab.ui.control.Label
        BaselineaveragesoilerosiononagriculturalplotsthhyrEditField  matlab.ui.control.EditField
        EditFieldLabel_18               matlab.ui.control.Label
        PercentoffarmersparticipatinginvaluechainEditField  matlab.ui.control.EditField
        EditFieldLabel_17               matlab.ui.control.Label
        PercentoffarmerseligibleforvaluechainparticipationEditField  matlab.ui.control.EditField
        PriceofferedtofarmersrupiahperunitofoutputEditField_2Label  matlab.ui.control.Label
        PricereceivedbybuyerrpperunitofoutputEditField  matlab.ui.control.EditField
        PricereceivedbybuyerrpperunitofoutputEditFieldLabel  matlab.ui.control.Label
        PriceofferedtofarmersrpperunitofoutputEditField  matlab.ui.control.EditField
        PriceofferedtofarmersrpperunitofoutputEditFieldLabel  matlab.ui.control.Label
        SummaryoflanduseTab             matlab.ui.container.Tab
        LandusesummarywithoutandwithvaluechainLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        LandscapemapsTab                matlab.ui.container.Tab
        Label_16                        matlab.ui.control.Label
        ClearplotButton                 matlab.ui.control.Button
        PlotlanduseButton               matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        NotusedButton                   matlab.ui.control.ToggleButton
        ValuechainButton                matlab.ui.control.ToggleButton
        AgroforestryButton              matlab.ui.control.ToggleButton
        RiceButton                      matlab.ui.control.ToggleButton
        HorticultureButton              matlab.ui.control.ToggleButton
        CoffeeButton                    matlab.ui.control.ToggleButton
        BandunglandscapemapLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ECDFsofoutcomesTab              matlab.ui.container.Tab
        PlotECDFButton                  matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        MaxquantileplottedEditField     matlab.ui.control.NumericEditField
        MaxquantileplottedLabel         matlab.ui.control.Label
        MinquantileplottedEditField     matlab.ui.control.NumericEditField
        TrimlefttailLabel               matlab.ui.control.Label
        ProfitsperhouseholdButton       matlab.ui.control.ToggleButton
        ProfitsperplotButton            matlab.ui.control.ToggleButton
        CarbonbiomassButton             matlab.ui.control.ToggleButton
        ErosionButton                   matlab.ui.control.ToggleButton
        UIAxes3                         matlab.ui.control.UIAxes
        ExportresultsTab                matlab.ui.container.Tab
        Label_13                        matlab.ui.control.Label
        Image9_18                       matlab.ui.control.Image
        Image9_17                       matlab.ui.control.Image
        Image9_16                       matlab.ui.control.Image
        Image9_15                       matlab.ui.control.Image
        Image9_14                       matlab.ui.control.Image
        Image9_13                       matlab.ui.control.Image
        Image9_12                       matlab.ui.control.Image
        Image9_11                       matlab.ui.control.Image
        Image9_10                       matlab.ui.control.Image
        Image9_9                        matlab.ui.control.Image
        Image9_8                        matlab.ui.control.Image
        Image9_7                        matlab.ui.control.Image
        Image9_6                        matlab.ui.control.Image
        Image9_5                        matlab.ui.control.Image
        Image9_4                        matlab.ui.control.Image
        Image9_3                        matlab.ui.control.Image
        Image9_2                        matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        DetailedoptimalsolutionsCheckBox  matlab.ui.control.CheckBox
        HouseholdinstanceindicesCheckBox  matlab.ui.control.CheckBox
        ExportcompleteLamp              matlab.ui.control.Lamp
        OptimallandusesummaryCheckBox   matlab.ui.control.CheckBox
        LandusesummaryCheckBox          matlab.ui.control.CheckBox
        OtherresultsCheckBox            matlab.ui.control.CheckBox
        HouseholdlevelresultsCheckBox   matlab.ui.control.CheckBox
        PlotlevelresultsCheckBox        matlab.ui.control.CheckBox
        LandscapelevelresultsCheckBox   matlab.ui.control.CheckBox
        DetailedyieldsandcostsperlanduseCheckBox  matlab.ui.control.CheckBox
        DataLabel                       matlab.ui.control.Label
        LandscapecharacteristicsCheckBox  matlab.ui.control.CheckBox
        PlotcharacteristicsCheckBox     matlab.ui.control.CheckBox
        HouseholdcharacteristicsCheckBox  matlab.ui.control.CheckBox
        LanduseparametersCheckBox       matlab.ui.control.CheckBox
        ScalaradjustmentparametersCheckBox  matlab.ui.control.CheckBox
        ExportresultsButton             matlab.ui.control.Button
        ResultsLabel                    matlab.ui.control.Label
        OtherparametersCheckBox         matlab.ui.control.CheckBox
        FarmereligibilityCheckBox       matlab.ui.control.CheckBox
        TransactioncostsCheckBox        matlab.ui.control.CheckBox
        ModelparametersLabel            matlab.ui.control.Label
        ScenarionameEditField           matlab.ui.control.EditField
        ScenarionameEditFieldLabel      matlab.ui.control.Label
        PricesCheckBox                  matlab.ui.control.CheckBox
        ResultsfilepathEditField        matlab.ui.control.EditField
        ResultsfilepathEditFieldLabel   matlab.ui.control.Label
        OptionalGlobalparametersTab     matlab.ui.container.Tab
        Label_14                        matlab.ui.control.Label
        FarmerdiscountrateEditField     matlab.ui.control.NumericEditField
        FarmerdiscountrateEditFieldLabel  matlab.ui.control.Label
        CommoditypricefortimberofferedtofarmersEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_10               matlab.ui.control.Label
        CommoditypriceforfruitofferedtofarmersEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_9                matlab.ui.control.Label
        CommoditypriceforriceofferedtofarmersEditField  matlab.ui.control.NumericEditField
        EditFieldLabel_7                matlab.ui.control.Label
        CommoditypriceforhortofferedtofarmersEditField  matlab.ui.control.NumericEditField
        CommoditypriceforcoffeecherriesofferedtofarmersEditField_2Label  matlab.ui.control.Label
        CommoditypriceforcoffeecherriesofferedtofarmersEditField  matlab.ui.control.NumericEditField
        CommoditypriceforcoffeecherriesofferedtofarmersmillionIDRLabel  matlab.ui.control.Label
        CreditlimitforoperationalphasesmillionIDREditField  matlab.ui.control.NumericEditField
        CreditlimitforoperationalphasesmillionIDREditFieldLabel  matlab.ui.control.Label
        CreditlimitforestablishmentofnewlandusesmillionIDREditField  matlab.ui.control.NumericEditField
        CreditlimitforestablishmentofnewlandusesEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        hh_data % default data
        input_data % default data
        vil_cluster_data  % default data
        hh_list % household indexes for each household instance
        hh_pop %number of instances of households in hh_state appearing in hh_list.
        pl_pop % details for each plot managed by model households. 
        R_mref % legacy - for map conversions
        cl_codes % reference codes for critical land classifications - not used
        hh_state % detailed information about the 499 households used to generate the household population in the model. 
        hh_tab % further details on 499 households used to generate the household population in the model. 
        lc_codes % details for each defined land use in the model 
        lu_codes % reference codes for the land uses available to households 
        map_tab % information for each map pixel within the study region
        sf_codes % reference codes for state forest land classifications - not used
        subd_poly %polygon structure for subdistrict boundaries 
        vil_poly %polygon structure for village boundaries - not used
        boundary % defines the boundary of the study area - index references attached to map tab pixels. 
        vil_clusters % model defined villages including household population. Used by buyer to decide on value chain entry. 
        lu_trans_cf_new % detailed costs and benefits of land uses
        dcf_file % filename for detailed costs and benefits of land uses
        n_luopts % number of land use options for the farmers in the model
        Y_row_param % defines the output rows to call when generating value chain land use
        eligible_struct % structure defining households and plots eligible for the value chain
        transaction_costs_scenarios % all transaction costs faced by farmers and the buyer. 
        farmer_transaction_costs % farmer level transaction costs
        buyer_parameters % buyer paramaters including transaction costs and discount rate
        r %farmer discount rate
        Y_npv_buyer % profits for the buyer to decide on value chain entry. 
        lp_parm % default linear programming parameters
        P_f % price for value chain outputs offered to farmers by the buyer
        n_LuOpts % number of land use options for the farmers in the model
        OS_0 % linear programming solutions before value chain optimisation 
        LP_0 % linear programming structure before value chain optimisation 
        V_OS_0 % household profits before optimisation
        credit_scenario % credit limits from value chain
        OS_1 % linear programming solutions for value chain optimisation 
        LP_1 % linear programming structure for value chain optimisation 
        a_min_buyer % minimum plot area for value chain as defined by the buyer
        V_OS_1 % household profits after optimisation
        lu_0 % total land uses summary by land use and slope before optimisation
        lu_1 % total land uses summary by land use and slope after optimisation
        not_used % total land use not used in ha
        pl_pop_baseline % duplicate pl_pop to manage baseline results
        lu_0b % duplicate land use summary to manage baseline results
        lu_1b % duplicate land use summary to manage baseline results
        not_used_baseline % duplicate land use summary to manage baseline results
        V % used to generate plot level npv outcomes
        map_outcomes % collection of map level outcomes before and after optimsation 
        Epop_0 % erosion before optimisation for plots
        Cpop_0  % carbon before optimisation for plots
        Vpop_0  % profits before optimisation for plots
        plot_outcomes % collection of plot level outcomes before and after optimsation 
        hh_outcomes % collection of household level outcomes before and after optimsation 
        Cfac % erosion parameter used to calculate results below
        Emap_1  %  erosion per pixel instance in map_tab after optimisation
        Epop_1  %  erosion per plot instance in pl_pop after optimisation
        Ehh_1 % erosion per household instance in hh_list after optimisation
        C_lu % carbon biomass parameter to calculate results below
        Cmap_1 %  carbon biomass per pixel instance in map_tab after optimisation
        Cpop_1 %  carbon biomass per plot instance in pl_pop after optimisation
        Chh_1 % carbon biomass per household instance in hh_list after optimisation
        Vmap_1 %  profits per pixel instance in map_tab after optimisation
        V_pop_1 %  profits per plot instance in pl_pop after optimisation
        Vhh_1 % household profits per household instance in hh_list after optimisation
        Emap_1_baseline  %  duplicate for baseline results
        Epop_1_baseline   %  duplicate for baseline results
        Ehh_1_baseline %  duplicate for baseline results
        Cmap_1_baseline  %  duplicate for baseline results
        Cpop_1_baseline  %  duplicate for baseline results
        Chh_1_baseline  %  duplicate for baseline results
        Vmap_1_baseline  %  duplicate for baseline results
        V_pop_1_baseline  %  duplicate for baseline results
        Vhh_1_baseline  %  duplicate for baseline results
        Eligible_hh_flag % flag attached to hh_list indicating whether household eligible for value chain
        Participate_hh_flag % flag attached to hh_list indicating whether household participates in value chain
        Eligible_plot_flag % flag attached to pl_pop indicating whether the plot is eligible for value chain
        p_f_multiplier %flag to turn on or off value chain scenarios
        value_chain_type %coffee, hort, rice, or aghroforestry
        value_chain_name %name of value chain scenario
        Cfac_valuechain  % value chain land use rulse parameter
        carbonstock % carbon biomass from value chain t/ha co2
        P_b  % price received by buyer for outputs. 
        buyerdiscountrate % buyer's annual discount rate
        adjustLabour %adjust parameters for value chain labour
        adjustCapital  %adjust for value chain capital 
        adjustY  %yield adjustment for value chain - in reference to all outputs. 
        adjustV  %timber volume adjust for value chain
        I_b %upfront investment cost for buyer per village
        C_bf % fixed annual transaction costs per farmer for the buyer
        C_bn % fixed annual transaction costs per plot for the buyer
        C_by % variable transaction costs per unit for the buyer
        I_f % upfront investment costs for the farmers
        C_ff % fixed annual transaction costs for the farmers
        C_fy % variable transaction costs per unit for the farmers
        flatflag  %flat sloped land flag
        moderateflag  % moderate sloped land flag
        steepslopeflag  % steep sloped land flag
        rentedflag  % rented eligibility flag
        ownedflag  % owner managed plot eligibility flag
        nosoilconsflag  % no soil conservation flag
        soilconsflag % soil conservation flag
        coffeeflag  % coffee eligibility flag
        hortflag  % horticulture eligibility flag
        riceflag  %rice eligibility flag
        agroforestyflag  %agroforestry eligibility flag
        Minimumedu % minimum education
        Minimumdistance  % Minimum distance to markets in kms
        riskflag  % Risk taking individuals eligibility flag
        socialflag  % Only socially connected households flag
        lu_opt % land uses for map
        x % map x axis
        y % map y axis
        clrs % map colours
        charArray % names for map
        min_competitive_price % minimum competitive price for buyer before transaction costs 
        sticky_parameter % farmer stickiness to change land use when profits under value chain are better.
        carbon_market % defines carbon market scenario
        lu_opt_mat % structure for plotting land uses on map
    end
    
    methods (Access = private)
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Load data
        try
            app.hh_data = load("Data\bandung_hhpop.mat", "hh_list", "hh_pop", "pl_pop");
            app.input_data = load("Data\bandung_input.mat", "R_mref", "cl_codes", "hh_state", "hh_tab", "lc_codes", "lu_codes", "map_tab", "sf_codes", "subd_poly", "vil_poly");
            app.boundary = load("Data\\boundary.mat");
            app.vil_cluster_data = load("Data\vil_clusters.mat");
            app.dcf_file = 'lu_trans_cf.mat';
        catch ME
            disp("Error loading data:");
            disp(ME.message);
        end
        end

        % Button pushed function: StartnewscenarioButton
        function StartnewscenarioButtonPushed(app, event)
            app.ConfirmLamp_2.Color = 'yellow';
            app.hh_list = app.hh_data.hh_list;
            app.hh_pop = app.hh_data.hh_pop;
            app.pl_pop = app.hh_data.pl_pop;
            app.R_mref = app.input_data.R_mref;
            app.cl_codes = app.input_data.cl_codes;
            app.hh_state = app.input_data.hh_state;
            app.hh_tab = app.input_data.hh_tab;
            app.lc_codes = app.input_data.lc_codes;
            app.lu_codes = app.input_data.lu_codes;
            app.map_tab = app.input_data.map_tab;
            app.sf_codes = app.input_data.sf_codes;
            app.subd_poly = app.input_data.subd_poly;
            app.vil_poly = app.input_data.vil_poly;
            app.vil_clusters = app.vil_cluster_data.vil_clusters;
            
            app.ConfirmLamp.Color = 'red';
            app.ConfirmtransactioncostsLamp.Color= 'red';
            app.eligibilityparametersconfirmedLamp.Color = 'red';
            app.DatapreparedLamp.Color = 'red';
            app.BuyermodelrunLamp.Color = 'red';
            app.BaselinemodelcompleteLamp.Color = 'red';
            app.RunpolicyluLamp.Color = 'red';
            app.DatapackagedLamp.Color = 'red';
            app.ExportcompleteLamp.Color = 'red';

            app.PricereceivedbybuyerrpperunitofoutputEditField.Value = '';
            app.MinimumpricebeforetransactioncostsEditField.Value = '';
            app.PriceofferedtofarmersrpperunitofoutputEditField.Value = '';
            app.PercentoffarmerseligibleforvaluechainparticipationEditField.Value = '';
            app.PercentoffarmersparticipatinginvaluechainEditField.Value = '';
            app.BaselineaveragesoilerosiononagriculturalplotsthhyrEditField.Value = '';
            app.valuechainaveragesoilerosiononagriculturalplotsthhyrEditField.Value = '';
            app.BaselineaveragecarbonbiomassonagriculturalplotstCo2EditField.Value  = '';
            app.valuechainaveragecarbonnagriculturalplotsthhyrEditField.Value = '';
            app.BaselineaveragenpvEditField.Value = '';
            app.valuechainaveragenpvEditField.Value = '';
            cla(app.UIAxes, 'reset')
            cla(app.UIAxes2, 'reset')
            cla(app.UIAxes3, 'reset')

            app.ConfirmLamp_2.Color = 'g';
        end

        % Button pushed function: ConfirmvaluechainparametersButton
        function ConfirmvaluechainparametersButtonPushed(app, event)
            app.ConfirmLamp.Color = 'yellow';
            drawnow(); 
            if app.IntroduceValueChainSwitch.Value == 'Yes'
                app.p_f_multiplier = 1;
            else
                app.p_f_multiplier = 0; %sets price to zero for scenario so value chain is not chosen. 
            end
            app.value_chain_name = string(app.ValuechainnameEditField.Value);
            app.value_chain_type = app.ValuechaincommodityDropDown.ValueIndex; % defines value chain - 1 coffee, 2 hort , 3 rice, 4 agroforestry (fruit), 5 agroforestry (timber)
            app.carbon_market = 0;
            if app.value_chain_type == 1
                app.Y_row_param = 19; %coffee
            elseif app.value_chain_type == 2
                app.Y_row_param = 21; %hort
            elseif app.value_chain_type == 3
                app.Y_row_param = 20; %rice
            elseif app.value_chain_type == 4
                 app.Y_row_param = 22; %fruit 
            elseif app.value_chain_type == 6
                 app.Y_row_param = 22; %fruit is used to model carbon with some minor adjustments. 
                 app.carbon_market = 1;
                 app.value_chain_type = 4;
            else
                app.Y_row_param = 24; %timber (volumes not outputs)
                app.value_chain_type = 4; % as fruit/timber the same inputs
            end
            app.Cfac_valuechain = app.RULSEerosioncovermanagementfactorEditField.Value; % value chain land use rulse parameter
            app.carbonstock = app.CarbonstockoflandusetChaEditField.Value; % carbon biomass from value chain t/ha co2
            app.P_b = app.NetpricereceivedbybuyerperunitofoutputmillionIDREditField.Value * 1000000; % price received by buyer for outputs. 
            app.buyerdiscountrate = app.BuyersannualdiscountrateEditField.Value; % buyer's annual discount rate
            app.sticky_parameter = app.Farmerstickiness_Editfield.Value;
            app.credit_scenario(1) = app.CreditofferedbyvaluechainforestablishmentmillionIDREditField.Value; %credit limit from value chain for estab
            app.credit_scenario(2) = app.CreditlimitofferedbyvaluechainforoperationsmillionIDREditField.Value; %credit limit from value chain for operation
            app.adjustLabour = [app.LandpreparationEditField.Value;...
                                app.PlantingEditField.Value;...
                                app.WeedingEditField.Value;...
                                app.FertiliserapplicationsEditField.Value;...
                                app.ChemicalapplicationsEditField.Value;...
                                app.HarvestingEditField.Value;...
                                app.PostharvesthandlingEditField.Value]; %adjust parameters for value chain labour
            app.adjustCapital = [app.SeedseedlinginputsEditField.Value;...
                                 app.FertiliserinputsEditField.Value;...
                                 app.ChemicalapplicationsEditField.Value]; %adjust for value chain capital 
            if app.Y_row_param < 24
                app.adjustY = repmat(app.YieldsEditField.Value,6,1); %yield adjustment for value chain (changes for all but only matters for outputs relative for the value chain) 
                app.adjustY(5) = 1; % timber yields for conversion keep the same regardless. 
                app.adjustV = 1;
            else
                app.adjustY = ones(6,1);
                app.adjustV = app.YieldsEditField.Value;
            end
            app.ConfirmLamp.Color = 'g';
        end

        % Button pushed function: ConfirmvaluechaintransactioncostsButton
        function ConfirmvaluechaintransactioncostsButtonPushed(app, event)
            app.ConfirmtransactioncostsLamp.Color= 'yellow';
            drawnow();
            %store transaction cost parameters
            app.I_b = app.UpfrontinvestmentcostforbuyerpervillagemillionIDREditField.Value * 1000000;
            app.C_bf = app.fixedannualfarmerforbuyerEditField.Value * 1000000;
            app.C_bn= app.fixedannualplotforbuyerEditField.Value * 1000000;
            app.C_by = app.variableforbuyerEditField.Value * 1000000;
            app.I_f = app.UpfrontinvestmentcostforfarmermillionIDREditField.Value * 1000000;
            app.C_ff = app.fixedannualcostforfarmerEditField.Value * 1000000;
            app.C_fy = app.variableforfarmerEditField.Value * 1000000;
            app.ConfirmtransactioncostsLamp.Color= 'g';
        end

        % Button pushed function: ConfirmeligibilityparametersButton
        function ConfirmeligibilityparametersButtonPushed2(app, event)
            app.eligibilityparametersconfirmedLamp.Color = 'yellow';
            drawnow();
            % store remaining parameters in relevant structures
            app.flatflag = app.FlatslopedplotsCheckBox.Value; %flat sloped land flag
            app.moderateflag = app.ModeratelyslopedplotsCheckBox.Value; % moderate sloped land flag
            app.steepslopeflag = app.SteepslopedplotsCheckBox.Value; % steep sloped land flag
            app.rentedflag = app.RentedplotsCheckBox.Value; % rented eligibility flag
            app.ownedflag  = app.OwnermanagedplotsCheckBox.Value; % owner managed plot eligibility flag
            app.nosoilconsflag = app.PlotscurrentlywithoutsoilconservationpracticesCheckBox.Value; % no soil conservation flag
            app.soilconsflag = app.PlotscurrentlywithsoilconservationpracticesCheckBox.Value; % soil conservation flag
            app.coffeeflag = app.PlotscurrentlyplantedwithcoffeeCheckBox.Value; % coffee eligibility flag
            app.hortflag = app.PlotscurrentlyplantedwithhorticultureCheckBox.Value; % horticulture eligibility flag
            app.riceflag = app.PlotscurrentlyplantedwithpaddyuplandsriceCheckBox.Value; %rice eligibility flag
            app.agroforestyflag = app.PlotscurrentlyplantedwithmixedagroforestryCheckBox.Value; %agroforestry eligibility flag
            app.Minimumedu = app.MinimumeducationinyearsEditField.Value; % minimum education in years
            app.Minimumdistance = app.MaximumdistancefrommarketinkmsEditField.Value * 1000; % Minimum distance to markets
            app.riskflag = app.OnlyrisktalkinghouseholdsCheckBox.Value; % Risk taking individuals eligibility flag
            app.socialflag = app.OnlyhouseholdswhoaresociallyconnectedCheckBox.Value; % Only socially connected households flag
            app.eligibilityparametersconfirmedLamp.Color = 'g';
        end

        % Button pushed function: PackagedataforanalysisButton
        function PackagedataforanalysisButtonPushed(app, event)
            app.DatapreparedLamp.Color = 'yellow';
            drawnow();
            % package all data into relevant structures for optimmisation
            app.map_tab = map_Set_lu(app.map_tab, app.pl_pop, app.lc_codes); 
            [app.lu_trans_cf_new, app.lu_codes, app.lc_codes, app.n_LuOpts] = Create_policy_lu(app.dcf_file, app.lu_codes, app.lc_codes, app.value_chain_name, app.Cfac_valuechain, app.carbonstock, app.value_chain_type, app.Y_row_param, app.carbon_market);
            app.lu_trans_cf_new.adjust.L(:,end) = app.adjustLabour;
            app.lu_trans_cf_new.adjust.K(:,end) = app.adjustCapital;
            app.lu_trans_cf_new.adjust.Y = app.adjustY; % either length 5 or 6 depending on whether it is a timber value chain
            app.lu_trans_cf_new.adjust.V(:,end) = app.adjustV;
            app.transaction_costs_scenarios = get_trans_cost_scenarios(app.P_b, app.I_b, app.C_bf, app.C_bn, app.C_by, app.I_f, app.C_ff, app.C_fy);
            app.pl_pop = Get_map2pl(app.pl_pop, app.hh_state);
            app.eligible_struct = get_eligibility(app.flatflag, app.moderateflag,app.steepslopeflag,...
                                                  app.rentedflag, app.ownedflag, app.nosoilconsflag, app.soilconsflag,...
                                                  app.coffeeflag, app.hortflag, app.riceflag, app.agroforestyflag, ...
                                                  app.Minimumedu,app.riskflag, app.socialflag,app.Minimumdistance);
            app.transaction_costs_scenarios = app.transaction_costs_scenarios;
            app.r = app.FarmerdiscountrateEditField.Value;  
            p_cof = app.CommoditypriceforcoffeecherriesofferedtofarmersEditField.Value * 1000000;
            p_hort = app.CommoditypriceforhortofferedtofarmersEditField.Value* 1000000;
            p_rice = app.CommoditypriceforriceofferedtofarmersEditField.Value* 1000000;
            p_fruit = app.CommoditypriceforfruitofferedtofarmersEditField.Value* 1000000;
            p_timber = app.CommoditypricefortimberofferedtofarmersEditField.Value* 1000000;
            app.lu_trans_cf_new.p_tab(1,2) =  {p_cof};
            app.lu_trans_cf_new.p_tab(2,2) =  {p_rice};
            app.lu_trans_cf_new.p_tab(3,2) =  {p_hort};
            app.lu_trans_cf_new.p_tab(4,2) =  {p_fruit};
            app.lu_trans_cf_new.p_tab(5,2) =  {p_timber};
            app.DatapreparedLamp.Color = 'g';
        end

        % Button pushed function: ValuechainbuyerdecisionsButton
        function ValuechainbuyerdecisionsButtonPushed(app, event)
            app.BuyermodelrunLamp.Color = 'yellow';
            drawnow();
            app.buyer_parameters = get_buyer_parameters(app.transaction_costs_scenarios, app.buyerdiscountrate);
            c1 = app.CreditlimitforestablishmentofnewlandusesmillionIDREditField.Value;
            c2 = app.CreditlimitforoperationalphasesmillionIDREditField.Value;
            creditbaseline = [c1, c2];
            w1 = 0.006;
            w2 = 0.006;
            [app.lp_parm, app.lu_trans_cf_new] = lp_Parm_default(app.r, creditbaseline, w1,w2, app.lu_trans_cf_new); 
            app.farmer_transaction_costs = get_farmer_trans_costs(app.lu_trans_cf_new, app.r, app.transaction_costs_scenarios);
            % solve buyer problem
            [app.vil_clusters, app.Y_npv_buyer] = solve_buyer_price(app.lu_trans_cf_new, app.buyer_parameters, app.farmer_transaction_costs, app.lp_parm,  app.n_LuOpts, app.vil_clusters);
            app.min_competitive_price = app.vil_clusters(1).P_f(1,1);
            % given optimal solution for each village, return most
            % profitable prices and eligible villages. 
            [app.vil_clusters, app.P_f, app.hh_list] = get_eligible_villages(app.vil_clusters, app.hh_list, app.buyer_parameters);
            app.BuyermodelrunLamp.Color = 'g';
        end

        % Button pushed function: 
        % FarmerlandusedecisionswithoutvaluechainButton
        function FarmerlandusedecisionswithoutvaluechainButtonPushed(app, event)
            app.BaselinemodelcompleteLamp.Color = 'yellow';
            drawnow();
            app.lu_trans_cf_new.adjust.P(end) = 0;
            app.lp_parm.options.Display = 'off'; 
            % Run farmer decision model without value chain for 499
            % household types in the model
            [app.OS_0, app.LP_0] = lp_Solve(app.lu_trans_cf_new, app.hh_state, app.lp_parm, app.n_LuOpts, app.farmer_transaction_costs);
            app.V_OS_0 = os_get_hh_NPV(app.OS_0, app.hh_list, app.hh_pop);
            app.BaselinemodelcompleteLamp.Color = 'g';
        end

        % Button pushed function: 
        % FarmerlandusedecisionswithvaluechainButton
        function FarmerlandusedecisionswithvaluechainButtonPushed(app, event)
            app.RunpolicyluLamp.Color = 'yellow';
            drawnow();
            %for eligible households, re-enter value chain parameters for
            %and re-run farmer decision model with value chain available. 
            app.lu_trans_cf_new.adjust.P(end) = app.p_f_multiplier * app.P_f;
            app.pl_pop = Get_eligible_plots(app.pl_pop, app.eligible_struct); 
            [app.pl_pop, app.a_min_buyer] = get_min_buyer_area(app.lu_trans_cf_new, app.pl_pop, app.buyer_parameters, app.P_f, app.Y_npv_buyer);
            app.hh_state = Get_eligible_hhs(app.a_min_buyer, app.hh_tab, app.hh_state, app.eligible_struct);
            app.lp_parm.credit = app.credit_scenario;
            app.LP_1 = lp_SetConstraints_EligibleLand(app.LP_0, app.hh_state, app.pl_pop, app.lu_trans_cf_new, app.lp_parm, app.n_LuOpts, app.farmer_transaction_costs);
            [app.OS_1, app.LP_1] = lp_Solve(app.lu_trans_cf_new, app.hh_state, app.lp_parm, app.n_LuOpts, app.farmer_transaction_costs, app.LP_1);
            app.OS_1 = lp_fixed_trans_costs_add(app.OS_1, app.farmer_transaction_costs, app.a_min_buyer, app.hh_state, app.Y_npv_buyer, app.buyer_parameters, app.P_f); 
            % farmers decide between scenario with and without value chain
            % based on optimal solutions of both 
            [app.hh_list, app.V_OS_1] = get_full_OS_allocation(app.hh_list, app.hh_pop, app.OS_0, app.OS_1, app.hh_state, app.sticky_parameter);
            % update land uses based on optimal solution
            app.pl_pop = os_Get_lu_pop(app.OS_0, app.OS_1, app.hh_state, app.pl_pop, app.n_LuOpts, app.hh_list);
            app.pl_pop_baseline = os_Get_lu_pop(app.OS_0, app.OS_0, app.hh_state, app.pl_pop, app.n_LuOpts, app.hh_list);
            [app.lu_0, app.lu_1] = os_Get_lu_mats(app.pl_pop, app.n_LuOpts);
            [app.lu_0b, app.lu_1b] = os_Get_lu_mats(app.pl_pop_baseline, app.n_LuOpts);
            app.not_used = sum(app.lu_0,2) - sum(app.lu_1,2);
            app.not_used_baseline = sum(app.lu_0b,2) - sum(app.lu_1b,2);
            app.RunpolicyluLamp.Color = 'g';
        end

        % Button pushed function: PackageresultsforexportButton
        function PackageresultsforexportButtonPushed(app, event)
            app.DatapackagedLamp.Color = 'yellow';
            drawnow();
            %package all erosion, carbon, and profit outcomes. 
            app.V = lp_Get_npv_mat(app.lu_trans_cf_new, app.lp_parm.r, app.lp_parm.w(3), app.n_LuOpts, app.farmer_transaction_costs); 
            app.map_outcomes = map_Get_baseline(app.map_tab, app.V, app.lc_codes, app.n_LuOpts);
            app.Epop_0 = app.map_outcomes.Emap_0(app.pl_pop.map_idx) .* app.pl_pop.prop;
            app.Cpop_0 = app.map_outcomes.Cmap_0(app.pl_pop.map_idx) .* app.pl_pop.prop;
            app.Vpop_0 = app.map_outcomes.Vmap_0(app.pl_pop.map_idx) .* app.pl_pop.prop;
            app.plot_outcomes = table(app.Epop_0, app.Cpop_0, app.Vpop_0, 'VariableNames', {'Epop_0', 'Cpop_0', 'Vpop_0'});
            app.hh_outcomes = map_Convert2_hh(app.map_outcomes.Emap_0, app.map_outcomes.Cmap_0, app.map_outcomes.Vmap_0, app.pl_pop, app.hh_pop, app.hh_list);
            app.hh_outcomes = addvars(app.hh_outcomes, app.V_OS_0, 'After', 'Vhh_0','NewVariableNames', 'V_OS_0');
            app.Cfac = []; 
            for lu = 1:size(app.lu_codes,1)
                if isempty(find(app.lc_codes.lu_type == app.lu_codes.luse(lu))) == false
                    app.Cfac = [app.Cfac app.lc_codes.c_fac(find(app.lc_codes.lu_type == app.lu_codes.luse(lu)))];  
                end
            end
            app.Cfac = [app.Cfac,0.9]; % add non-ag land use manually.
            [app.Emap_1, app.Epop_1, app.Ehh_1] = map_Get_Erosion(app.map_tab,app.hh_list, app.hh_pop, app.pl_pop, app.n_LuOpts, app.Cfac);
            [app.Emap_1_baseline, app.Epop_1_baseline, app.Ehh_1_baseline] = map_Get_Erosion(app.map_tab, app.hh_list, app.hh_pop, app.pl_pop_baseline, app.n_LuOpts, app.Cfac);
        
            app.C_lu = [];
            for lu = 1:size(app.lu_codes,1)
                if isempty(find(app.lc_codes.lu_type == app.lu_codes.luse(lu))) == false
                    app.C_lu  = [app.C_lu  app.lc_codes.C_stock(find(app.lc_codes.lu_type == app.lu_codes.luse(lu)))];
                end
            end
            app.C_lu = [app.C_lu,0]; % add non-ag land use manually. 
            [app.Cmap_1, app.Cpop_1, app.Chh_1] = map_Get_Cstock(app.map_tab, app.hh_list, app.hh_pop, app.pl_pop, app.lc_codes, app.n_LuOpts, app.C_lu);
            [app.Cmap_1_baseline, app.Cpop_1_baseline, app.Chh_1_baseline] = map_Get_Cstock(app.map_tab, app.hh_list, app.hh_pop, app.pl_pop_baseline, app.lc_codes, app.n_LuOpts, app.C_lu); 

            [app.Vmap_1, app.V_pop_1, app.Vhh_1] = map_Get_npv(app.pl_pop, app.hh_list, app.hh_pop, app.V, app.map_outcomes.Vmap_0, app.n_LuOpts); 
            [app.Vmap_1_baseline, app.V_pop_1_baseline, app.Vhh_1_baseline] = map_Get_npv(app.pl_pop_baseline, app.hh_list, app.hh_pop, app.V, app.map_outcomes.Vmap_0, app.n_LuOpts); 
            
            app.Eligible_hh_flag = app.hh_list(:,2);
            app.Participate_hh_flag = app.hh_list(:,3);
            app.Eligible_plot_flag = app.pl_pop.scenario_idx;
            app.map_outcomes = addvars(app.map_outcomes,app.Emap_1_baseline, app.Cmap_1_baseline, app.Vmap_1_baseline, app.Emap_1, app.Cmap_1, app.Vmap_1, 'After', 'Vmap_0', 'NewVariableNames', {'Emap_1_baseline', 'Cmap_1_baseline', 'Vmap_1_baseline','Emap_1', 'Cmap_1', 'Vmap_1'});
            app.plot_outcomes = addvars(app.plot_outcomes, app.Epop_1_baseline, app.Cpop_1_baseline, app.V_pop_1_baseline, app.Epop_1, app.Cpop_1, app.V_pop_1, app.Eligible_plot_flag , 'After', 'Vpop_0','NewVariableNames', {'Epop_1_baseline', 'Cpop_1_baseline', 'Vpop_1_baseline', 'Epop_1', 'Cpop_1', 'Vpop_1', 'Eligible_plot_flag'});
            app.hh_outcomes = addvars(app.hh_outcomes, app.Ehh_1_baseline, app.Chh_1_baseline, app.Vhh_1_baseline, app.Ehh_1, app.Chh_1, app.Vhh_1, app.V_OS_1, app.Eligible_hh_flag, app.Participate_hh_flag, 'After', 'V_OS_0', 'NewVariableNames', {'Ehh_1_baseline', 'Chh_1_baseline', 'Vhh_1_baseline','Ehh_1', 'Chh_1', 'Vhh_1', 'V_OS_1', 'Eligible_hh_flag', 'Participate_hh_flag'});
        
            % Display summary results 
            app.PricereceivedbybuyerrpperunitofoutputEditField.Value = string(app.P_b);
            app.MinimumpricebeforetransactioncostsEditField.Value = string(app.min_competitive_price);
            app.PriceofferedtofarmersrpperunitofoutputEditField.Value = string(app.P_f);
            app.PercentoffarmerseligibleforvaluechainparticipationEditField.Value = string(sum(app.Eligible_hh_flag)/size(app.hh_outcomes,1));
            app.PercentoffarmersparticipatinginvaluechainEditField.Value = string(sum(app.Participate_hh_flag)/size(app.hh_outcomes,1));
            app.BaselineaveragesoilerosiononagriculturalplotsthhyrEditField.Value = string(mean(app.Epop_1_baseline));
            app.valuechainaveragesoilerosiononagriculturalplotsthhyrEditField.Value = string(mean(app.Epop_1));
            app.BaselineaveragecarbonbiomassonagriculturalplotstCo2EditField.Value  = string(mean(app.Cpop_1_baseline));
            app.valuechainaveragecarbonnagriculturalplotsthhyrEditField.Value = string(mean(app.Cpop_1));
            app.BaselineaveragenpvEditField.Value = string(mean(app.V_OS_0));
            app.valuechainaveragenpvEditField.Value = string(mean(app.V_OS_1));
            
            % Plot land use summary
            app.clrs = [ 0.0 0.4 0.7;
                         0.5 0.7 0.2;
                         0.9 0.7 0.1;
                         0.5 0.2 0.6;
                         1.0 0.5 0.5;
                         0.35 0.35 0.35;
                         0.1 1.0 1.0];

            app.lu_1b = [app.lu_1b app.not_used_baseline];
            app.lu_1 = [app.lu_1 app.not_used];
            LU = [app.lu_1b; app.lu_1];
            b = bar(app.UIAxes, LU, 'stacked');
            for k = 1:length(b)
                b(k).FaceColor = app.clrs(k, :);
            end
            categories = categorical({'slight_0','moderate_0','steep_0','slight_1','moderate_1','steep_1'});
            app.UIAxes.XTick = 1:length(categories);
            app.UIAxes.XTickLabel = categories;
            charArray1 = {"Coffee", "Horticulture", "Rice", "Agroforestry", app.ValuechainnameEditField.Value, "Not used"};
            charArray1 = cat(1, charArray1{:});
            lgd = legend(app.UIAxes, charArray1, 'Location', 'northeast', 'AutoUpdate', 'off','Box','off');
            set(lgd, 'Direction', 'reverse');
            set(lgd, 'FontSize', 12, 'FontName','Calibri');
            xline(app.UIAxes, 3.5, 'LineWidth', 2);
            xlabel(app.UIAxes, 'Plot slope', 'FontSize',14, 'FontName','Calibri');
            ylabel(app.UIAxes, 'Area of land use (Ha)', 'FontSize',14, 'FontName','Calibri');
            set(app.UIAxes, 'Box', 'off');

            % Plot map of optimal solution. 
            app.UIAxes2.XAxis.Visible = 'off';
            app.UIAxes2.YAxis.Visible = 'off';
            [app.lu_opt, app.lu_opt_mat] = map_LU_opt(app.map_tab,app.pl_pop);
            app.lu_opt(app.map_tab.lcover == 13) = 13; % settlement points remain constant

            app.x = app.lu_opt_mat.x;
            app.y = app.lu_opt_mat.y;  
            app.charArray = {"Coffee", "Horticulture", "Rice", "Agroforestry", app.ValuechainnameEditField.Value, "Not used", "Settlement"};
            app.DatapackagedLamp.Color = 'g';
        end

        % Button pushed function: PlotlanduseButton
        function PlotlanduseButtonPushed(app, event)
            if app.CoffeeButton.Value == 1
                landuse = 1;
            elseif app.HorticultureButton.Value == 1
                landuse = 2;
            elseif app.RiceButton.Value == 1
                landuse = 3;
            elseif app.AgroforestryButton.Value == 1
                landuse = 4;
            elseif app.ValuechainButton.Value == 1
                landuse = 5;
            else 
                landuse = 6;
            end
            LU_map(app.UIAxes2, app.lu_opt_mat.x, app.lu_opt_mat.y, app.lu_opt_mat.lu, landuse, app.clrs, app.charArray, app.subd_poly, app.boundary, app.map_tab, 1);
        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            cla(app.UIAxes2, 'reset')
            app.UIAxes2.XAxis.Visible = 'off';
            app.UIAxes2.YAxis.Visible = 'off';
        end

        % Button pushed function: PlotECDFButton
        function PlotECDFButtonPushed(app, event)
            cla(app.UIAxes3, 'reset')
            scenario_names = {'Without value chain'...
                              'With Value chain'};
            colours = [0 0 0
                       1.0 0.5 0.5];
            linetypes = ["-" "--"];
            left_tail = app.MinquantileplottedEditField.Value / 100;
            right_tail = app.MaxquantileplottedEditField.Value/ 100;

            if app.ErosionButton.Value == 1
                 left_tail = quantile(app.Epop_1, left_tail);
                 right_tail = quantile(app.Epop_1, right_tail);
                 [f_b,x_b] = ecdf(app.Epop_1_baseline);
                 [f_v,x_v] = ecdf(app.Epop_1);   
                 plot(app.UIAxes3,x_b,f_b, 'color', colours(1,:), LineStyle=linetypes(1), LineWidth= 2)
                 hold (app.UIAxes3, 'on')
                 plot(app.UIAxes3,x_v,f_v, 'color', colours(2,:), LineStyle=linetypes(2), LineWidth= 2)
                 grid (app.UIAxes3, 'on')
                 title(app.UIAxes3,'Distribution of landscape erosion on plots managed by households (t/ha/year)','FontSize', 18, 'FontName', 'Calibri')
                 xlabel(app.UIAxes3,'Erosion (t/ha/yr)','FontSize', 18, 'FontName', 'Calibri')
                 set(app.UIAxes3, 'Box', 'off');   
                 axis(app.UIAxes3,[left_tail,right_tail,0,1])
                 hold (app.UIAxes3, 'off')
                 legend(app.UIAxes3, scenario_names, 'Location', 'eastoutside','FontSize', 14, 'FontName', 'Calibri', 'Box','off');
        
            elseif app.CarbonbiomassButton.Value == 1
                 left_tail = quantile(app.Cpop_1, left_tail);
                 right_tail = quantile(app.Cpop_1, right_tail);
                 [f_b,x_b] = ecdf(app.Cpop_1_baseline);
                 [f_v,x_v] = ecdf(app.Cpop_1);   
                 plot(app.UIAxes3,x_b,f_b, 'color', colours(1,:), LineStyle=linetypes(1), LineWidth= 2)
                 hold (app.UIAxes3, 'on')
                 plot(app.UIAxes3,x_v,f_v, 'color', colours(2,:), LineStyle=linetypes(2), LineWidth= 2)
                 grid (app.UIAxes3, 'on')
                 title(app.UIAxes3,'Distribution of landscape carbon biomass on plots managed by households (t/Co2/ha)','FontSize', 18, 'FontName', 'Calibri')
                 xlabel(app.UIAxes3,'Carbon biomass (t/Co2/Ha)','FontSize', 18, 'FontName', 'Calibri')
                 set(app.UIAxes3, 'Box', 'off');
                 axis(app.UIAxes3,[left_tail,right_tail,0,1])
                 hold (app.UIAxes3, 'off')
                 legend(app.UIAxes3, scenario_names, 'Location', 'eastoutside','FontSize', 14, 'FontName', 'Calibri', 'Box','off');

            elseif app.ProfitsperplotButton.Value == 1
                 left_tail = quantile(app.V_pop_1_baseline, left_tail);
                 right_tail = quantile(app.V_pop_1, right_tail);
                 [f_b,x_b] = ecdf(app.V_pop_1_baseline);
                 [f_v,x_v] = ecdf(app.V_pop_1);   
                 plot(app.UIAxes3,x_b,f_b, 'color', colours(1,:), LineStyle=linetypes(1), LineWidth= 2)
                 hold (app.UIAxes3, 'on')
                 plot(app.UIAxes3,x_v,f_v, 'color', colours(2,:), LineStyle=linetypes(2), LineWidth= 2)
                 grid (app.UIAxes3, 'on')
                 title(app.UIAxes3,'Distribution of agricultural profits on plots managed by households (million rupiah npv over 20 years per ha)','FontSize', 16, 'FontName', 'Calibri')
                 xlabel(app.UIAxes3,'Million rupiah npv over 20 years per ha','FontSize', 16, 'FontName', 'Calibri')
                 set(app.UIAxes3, 'Box', 'off');  
                 axis(app.UIAxes3,[left_tail,right_tail,0,1])
                 hold (app.UIAxes3, 'off')
                 legend(app.UIAxes3, scenario_names, 'Location', 'eastoutside','FontSize', 14, 'FontName', 'Calibri', 'Box','off');

            else 
                 left_tail = quantile(app.V_OS_0, left_tail);
                 right_tail = quantile(app.V_OS_1, right_tail);
                 [f_b,x_b] = ecdf(app.V_OS_0);
                 [f_v,x_v] = ecdf(app.V_OS_1);   
                 plot(app.UIAxes3,x_b,f_b, 'color', colours(1,:), LineStyle=linetypes(1), LineWidth= 2)
                 hold (app.UIAxes3, 'on')
                 plot(app.UIAxes3,x_v,f_v, 'color', colours(2,:), LineStyle=linetypes(2), LineWidth= 2)
                 grid (app.UIAxes3, 'on')
                 title(app.UIAxes3,'Distribution of household profits (million rupiah npv over 20 years per ha)','FontSize', 16, 'FontName', 'Calibri')
                 xlabel(app.UIAxes3,'Million rupiah npv over 20 years','FontSize', 16, 'FontName', 'Calibri')
                 set(app.UIAxes3, 'Box', 'off'); 
                 axis(app.UIAxes3,[left_tail,right_tail,0,1])
                 hold (app.UIAxes3, 'off')
                 legend(app.UIAxes3, scenario_names, 'Location', 'eastoutside','FontSize', 14, 'FontName', 'Calibri', 'Box','off');
            end

        end

        % Button pushed function: ExportresultsButton
        function ExportresultsButtonPushed(app, event)
             app.ExportcompleteLamp.Color = 'yellow';
             drawnow(); 
             results_filepath = app.ResultsfilepathEditField.Value;
             scenario_name = app.ScenarionameEditField.Value;

            if app.LanduseparametersCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_startinglanduseparameters.csv']);
                writetable(app.lc_codes, filename, 'WriteRowNames', true)
            end

            if app.HouseholdcharacteristicsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_householdcharacteristics.csv']);
                writetable(app.hh_tab, filename, 'WriteRowNames', true)
            end

            if app.PlotcharacteristicsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_plotcharacteristics.csv']);
                writetable(app.pl_pop, filename, 'WriteRowNames', true)
            end

            if app.LandscapecharacteristicsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_landscapecharacteristics.csv']);
                writetable(app.map_tab, filename, 'WriteRowNames', true)
            end

            if app.LandusesummaryCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_initiallandusesummary.csv']);
                lu = array2table(app.lu_0, "RowNames",{'Flat', 'Moderate', 'Steep'}, "Variablenames", {'Coffee', 'Hort', 'Rice', 'Agroforestry', 'valuechainsscenario'});
                writetable(lu, filename, 'WriteRowNames', true)
            end

            if app.HouseholdinstanceindicesCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_householdinstanceindices.csv']);
                writematrix(app.hh_list, filename)
            end

            if app.ScalaradjustmentparametersCheckBox.Value == 1
                Pref_weights = app.lu_trans_cf_new.adjust.Pref_weights;
                rowNames = {'coffee', 'hort', 'rice', 'fruit', 'timber', 'valuechainscenario'};
                columnNames = {'Flat', 'Moderate', 'Steep'};
                Pref_weights_table = array2table(Pref_weights, 'VariableNames', columnNames, 'RowNames', rowNames);
                filename = fullfile(results_filepath, [scenario_name, '_preference_weights.csv']);
                writetable(Pref_weights_table, filename, 'WriteRowNames', true);
                L = app.lu_trans_cf_new.adjust.L;
                K = app.lu_trans_cf_new.adjust.K;
                Y = [app.lu_trans_cf_new.adjust.Y ; app.lu_trans_cf_new.adjust.V];
                rowNames_L = app.lu_trans_cf_new.adjust.names(1:7);
                rowNames_K = app.lu_trans_cf_new.adjust.names(8:10);
                rowNames_Y = {'Coffee', 'Hort', 'Rice', 'Fruit', 'Timber', 'ValueChain','TimberVolumes'};
                columnNames = {'Coffee', 'Hort', 'Rice', 'Agroforestry', 'ValueChain'};
                L_table = array2table(L, 'VariableNames', columnNames, 'RowNames', rowNames_L);
                K_table = array2table(K, 'VariableNames', columnNames, 'RowNames', rowNames_K);
                Y_table = array2table(Y, 'VariableNames', {'Price_per_unit_rupee'}, 'RowNames', rowNames_Y);   
                filename_L = fullfile(results_filepath, [scenario_name, '_LabourScalarAdjustments.csv']);
                filename_K = fullfile(results_filepath, [scenario_name, '_CapitalScalarAdjustments.csv']);
                filename_Y = fullfile(results_filepath, [scenario_name, '_YieldScalarAdjustments.csv']);
                writetable(L_table, filename_L, 'WriteRowNames', true);
                writetable(K_table, filename_K, 'WriteRowNames', true);
                writetable(Y_table, filename_Y, 'WriteRowNames', true);
            end

            if app.DetailedyieldsandcostsperlanduseCheckBox.Value == 1
                lut = app.lu_trans_cf_new.lut_cf;
                filename = fullfile(results_filepath, [scenario_name, '_landuse_profiles.mat']);
                save(filename, 'lut') %saves as mat file due to 3 dimensional matrix. 
            end

            if app.PricesCheckBox.Value == 1
                app.lu_trans_cf_new.p_tab(end,2) = {app.P_f};
                filename = fullfile(results_filepath, [scenario_name, '_farmerprices.csv']);
                writetable(app.lu_trans_cf_new.p_tab, filename, 'WriteRowNames', true);
            end
            
            if app.TransactioncostsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_transactioncosts.csv']);
                writetable(app.transaction_costs_scenarios, filename,'WriteRowNames', true);
            end
            
            if app.FarmereligibilityCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_farmereligibilityparameters.csv']);
                combinedTable = [app.eligible_struct.plots, app.eligible_struct.hh];
                writetable(combinedTable, filename, 'WriteRowNames', true);
            end
            
            if app.OtherparametersCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_otherparameters.csv']);
                combinedTable = [app.lp_parm.r; app.lp_parm.credit(1); app.lp_parm.credit(2); app.credit_scenario(1); app.credit_scenario(2); app.lp_parm.w(1);app.lp_parm.w(2); app.buyer_parameters.discount_rate_buyer];
                rowNames = {'farmer_discountrate', 'credit_limit_baseline_establishment_midr', 'credit_limit_baseline_operational_midr', 'credit_limit_valuechain_operational_midr', 'credit_limit_valuechain_establishment_midr', 'wage_rate_hirelabour_midr', 'wage_rate_earned_midr', 'buyer_discountrate'};
                combinedTable  = array2table(combinedTable , 'RowNames', rowNames);
                writetable(combinedTable, filename,'WriteRowNames', true);
            end

            if app.HouseholdlevelresultsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_householdlevelresults.csv']);
                writetable(app.hh_outcomes, filename)
            end

            if app.PlotlevelresultsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_plotlevelresults.csv']);
                writetable(app.plot_outcomes, filename)
            end

            if app.LandscapelevelresultsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_landscapelevelresults.csv']);
                writetable(app.map_outcomes, filename)
            end

            if app.OptimallandusesummaryCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_optimallandusesummary.csv']);
                app.lu_1b = [app.lu_1b app.not_used_baseline];
                app.lu_1 = [app.lu_1 app.not_used];
                lu = [app.lu_1b; app.lu_1];
                lu = array2table(lu, "RowNames",{'Flat', 'Moderate', 'Steep','Flat', 'Moderate', 'Steep'}, "Variablenames", {'Coffee', 'Hort', 'Rice', 'Agroforestry', 'valuechainsscenario','NotUsed'});
                writetable(lu, filename, 'WriteRowNames', true)
            end

            if app.OtherresultsCheckBox.Value == 1
                filename = fullfile(results_filepath, [scenario_name, '_otherresults.csv']);
                a_min = app.a_min_buyer;
                combinedtable = [a_min app.P_f];
                writematrix(combinedtable , filename)
            end

            if app.DetailedoptimalsolutionsCheckBox.Value == 1
                OS = app.OS_0;
                filename = fullfile(results_filepath, [scenario_name, '_optimalsolutionbaseline.mat']);
                save(filename, 'OS') %saves as mat file due to complexity of structure
                OS = app.OS_1;
                filename = fullfile(results_filepath, [scenario_name, '_optimalsolutionValueChain.mat']);
                save(filename, 'OS') %saves as mat file due to complexity of structure
            end
             app.ExportcompleteLamp.Color = 'g';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = '1. Overview';
            app.Panel = uipanel(app.OverviewTab);
            app.OverviewofthemodelLabel = uilabel(app.Panel);
            app.OverviewofthemodelLabel.Text = 'Overview of the model';
            app.HowtousethemodelLabel = uilabel(app.Panel);
            app.HowtousethemodelLabel.Text = 'How to use the model';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = {'The IndoMod Value Chains model simulates smallholder land use decisions for the peri-urban uplands of Bandung, Indonesia given the introduction of value chain designs. The aim of the model is to help the user understand land use changes, and subsequent erosion, carbon biomass, and smallholder income changes under different value chain designs. This model was supported by ACIAR project Agricultural Policy Research to Support Natural Resource Management in Indonesia’s Upland Landscapes'; '( #ADP/2015/043).'};
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = {'In the model it is possible to test value chain interventions for the four major land uses in the Bandung uplands - Coffee, Horticulture, Rice, and Mixed Agroforestry. It is also possible to test non-value chain scenarios (say for changes in the global parameters on the last sheet) by switching off the value chain scenario on Tab 2. Under this scenario, all scenario results will match the baseline results for the defined global parameters. '; ''; 'Move through the tabs sequentially to define parameters and run the model. Before progressing through to the next tab, make sure all button lamps are ''green'' on the tab (where relevant) to make sure the parameters have been successfully defined in the model. To test new value chain designs without restarting the application, press the reset button on Tab 2a. It is best to restart the process from the start when changing any parameters. '; ''; 'For more details about how to use the model, please refer to the help icons and the user guide https://cloud.une.edu.au/index.php/s/fIBH9E94mIGx9NZ. The underlying code is available on Github - https://github.com/Daniel-JW-Hill/INDOMOD-Value-Chains.'};
            app.IndoModValueChainsModelLabel = uilabel(app.OverviewTab);
            app.IndoModValueChainsModelLabel.Text = 'IndoMod Value Chains Model';
            app.Label = uilabel(app.OverviewTab);
            app.Label.Text = 'An agent based simulation model for smallholder value chain interventions for the peri urban uplands of Bandung, Indonesia.';
            app.Image = uiimage(app.OverviewTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logos.png');
            app.Image33 = uiimage(app.OverviewTab);
            app.Image33.ImageSource = fullfile(pathToMLAPP, 'une.png');
            app.aValuechainparametersTab = uitab(app.TabGroup);
            app.aValuechainparametersTab.Title = '2a. Value chain parameters';
            app.ValuechaincommodityDropDownLabel = uilabel(app.aValuechainparametersTab);
            app.ValuechaincommodityDropDownLabel.Text = 'Value chain commodity ';
            app.ValuechaincommodityDropDown = uidropdown(app.aValuechainparametersTab);
            app.ValuechaincommodityDropDown.Items = {'Coffee', 'Horticulture', 'Rice', 'Agroforestry (fruits)', 'Agroforestry (timber)', 'Carbon/PES market'};
            app.ValuechaincommodityDropDown.Value = 'Coffee';
            app.IntroduceValueChainSwitchLabel = uilabel(app.aValuechainparametersTab);
            app.IntroduceValueChainSwitchLabel.Text = 'Introduce Value Chain?';
            app.IntroduceValueChainSwitch = uiswitch(app.aValuechainparametersTab, 'slider');
            app.IntroduceValueChainSwitch.Items = {'No', 'Yes'};
            app.IntroduceValueChainSwitch.Value = 'Yes';
            app.ValuechainnameEditFieldLabel = uilabel(app.aValuechainparametersTab);
            app.ValuechainnameEditFieldLabel.Text = 'Value chain name';
            app.ValuechainnameEditField = uieditfield(app.aValuechainparametersTab, 'text');
            app.ValuechainnameEditField.Placeholder = 'Value_Chain';
            app.ValuechainnameEditField.Value = 'Value_Chain';
            app.RULSEerosioncovermanagementfactorEditFieldLabel = uilabel(app.aValuechainparametersTab);
            app.RULSEerosioncovermanagementfactorEditFieldLabel.Text = 'RULSE erosion cover management factor';
            app.RULSEerosioncovermanagementfactorEditField = uieditfield(app.aValuechainparametersTab, 'numeric');
            app.RULSEerosioncovermanagementfactorEditField.Limits = [0 1];
            app.RULSEerosioncovermanagementfactorEditField.Value = 0.3;
            app.CarbonstockoflandusetChaEditFieldLabel = uilabel(app.aValuechainparametersTab);
            app.CarbonstockoflandusetChaEditFieldLabel.Text = {'Carbon stock of land use (t C/ha)'; ''};
            app.CarbonstockoflandusetChaEditField = uieditfield(app.aValuechainparametersTab, 'numeric');
            app.CarbonstockoflandusetChaEditField.Limits = [0 Inf];
            app.CarbonstockoflandusetChaEditField.Value = 40;
            app.NetpricereceivedbybuyerperunitofoutputmillionIDREditFieldLabel = uilabel(app.aValuechainparametersTab);
            app.NetpricereceivedbybuyerperunitofoutputmillionIDREditFieldLabel.Text = 'Net price received by buyer per unit of output (million IDR)';
            app.NetpricereceivedbybuyerperunitofoutputmillionIDREditField = uieditfield(app.aValuechainparametersTab, 'numeric');
            app.NetpricereceivedbybuyerperunitofoutputmillionIDREditField.Limits = [0 Inf];
            app.NetpricereceivedbybuyerperunitofoutputmillionIDREditField.Value = 0.012;
            app.BuyersannualdiscountrateEditFieldLabel = uilabel(app.aValuechainparametersTab);
            app.BuyersannualdiscountrateEditFieldLabel.Text = 'Buyer''s annual discount rate';
            app.BuyersannualdiscountrateEditField = uieditfield(app.aValuechainparametersTab, 'numeric');
            app.BuyersannualdiscountrateEditField.Limits = [0 1];
            app.BuyersannualdiscountrateEditField.Value = 0.1;
            app.CreditofferedbyvaluechainforestablishmentEditFieldLabel = uilabel(app.aValuechainparametersTab);
            app.CreditofferedbyvaluechainforestablishmentEditFieldLabel.Text = 'Credit limit offered by value chain for establishment  (million IDR) ';
            app.CreditofferedbyvaluechainforestablishmentmillionIDREditField = uieditfield(app.aValuechainparametersTab, 'numeric');
            app.CreditofferedbyvaluechainforestablishmentmillionIDREditField.Limits = [0 Inf];
            app.CreditofferedbyvaluechainforestablishmentmillionIDREditField.Value = 3;
            app.CreditofferedbyvaluechainforoperationsmillionIDREditFieldLabel = uilabel(app.aValuechainparametersTab);
            app.CreditofferedbyvaluechainforoperationsmillionIDREditFieldLabel.Text = 'Credit limit offered by value chain for operations (million IDR) ';
            app.CreditlimitofferedbyvaluechainforoperationsmillionIDREditField = uieditfield(app.aValuechainparametersTab, 'numeric');
            app.CreditlimitofferedbyvaluechainforoperationsmillionIDREditField.Limits = [0 Inf];
            app.CreditlimitofferedbyvaluechainforoperationsmillionIDREditField.Value = 2;
            app.Label_2 = uilabel(app.aValuechainparametersTab);
            app.Label_2.Text = 'In this section, choose whether to run a value chain scenario, and if so enter the key parameters for the value chain scenario. ';
            app.ValuechainlandusecharacteristicsLabel = uilabel(app.aValuechainparametersTab);
            app.ValuechainlandusecharacteristicsLabel.Text = 'Value chain land use characteristics';
            app.Image25 = uiimage(app.aValuechainparametersTab);
            app.Image25.ImageSource = fullfile(pathToMLAPP, 'ploticon.png');
            app.KeyparametersLabel = uilabel(app.aValuechainparametersTab);
            app.KeyparametersLabel.Text = 'Key parameters';
            app.PricesanddiscountratesLabel = uilabel(app.aValuechainparametersTab);
            app.PricesanddiscountratesLabel.Text = 'Prices and discount rates';
            app.ValuechaincreditsupportLabel = uilabel(app.aValuechainparametersTab);
            app.ValuechaincreditsupportLabel.Text = 'Value chain credit support';
            app.Image26 = uiimage(app.aValuechainparametersTab);
            app.Image26.ImageSource = fullfile(pathToMLAPP, 'rupiah.png');
            app.Image27 = uiimage(app.aValuechainparametersTab);
            app.Image27.ImageSource = fullfile(pathToMLAPP, 'tenureicon.png');
            app.Image9_25 = uiimage(app.aValuechainparametersTab);
            app.Image9_25.Tooltip = {'This parameter scales soil erosion outcomes depending on the land use, and is bounded between 0 and 1. Some example parameters are: forest = 0.01, agroforestry = 0.2, coffee = 0.3, rice = 0.2, horticulture 0.8, cleared land = 1. Expected outcomes from value chain land uses and soil conservation activities under the value chain can be adjusted through this parameter. '};
            app.Image9_25.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_26 = uiimage(app.aValuechainparametersTab);
            app.Image9_26.Tooltip = {'Represents the total carbon stock captured per hectare under the value chain. Some example parameters include: 250 for undisturbed forst, 40 for coffee, 60 for mixed agroforestry, and 0 for rice, annual crops and cleared land. Note this parameter has no influence on the prices received/offered in the carbon/PES option - this needs to be set manually.'};
            app.Image9_26.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_27 = uiimage(app.aValuechainparametersTab);
            app.Image9_27.Tooltip = {'Default commodity prices are defined in the global parameters sheet. These prices can be used for reference and changed by the user. If the value chain is a carbon or PES market, this price represents the payment per hectare the buyer will receive.'};
            app.Image9_27.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_29 = uiimage(app.aValuechainparametersTab);
            app.Image9_29.Tooltip = {'Credit limits are also set globally on the global parameters sheet. The credit limits for the value chain on the other hand values allow value chain participants to access higher credit limits than what can be achieved in the open market. '};
            app.Image9_29.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.StartnewscenarioButton = uibutton(app.aValuechainparametersTab, 'push');
            app.StartnewscenarioButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewscenarioButtonPushed, true);
            app.StartnewscenarioButton.Icon = fullfile(pathToMLAPP, 'soil.png');
            app.StartnewscenarioButton.Text = 'Start new scenario';
            app.ConfirmLamp_2 = uilamp(app.aValuechainparametersTab);
            app.BuyersannualdiscountrateEditField_2Label = uilabel(app.aValuechainparametersTab);
            app.BuyersannualdiscountrateEditField_2Label.Text = 'Likelihood of farmer adopting value chain (if eligible and its is profitable to do so)';
            app.Farmerstickiness_Editfield = uieditfield(app.aValuechainparametersTab, 'numeric');
            app.Farmerstickiness_Editfield.Limits = [0 1];
            app.Farmerstickiness_Editfield.Value = 1;
            app.Image9_33 = uiimage(app.aValuechainparametersTab);
            app.Image9_33.Tooltip = {'Even when it is profitable to do so, farmers may not be willing to participate in value chains for a variety of reasons - i.e. trust, aversion to change, etc. This parameter defines the likelihood that a smallholder, who is eligible to participate in the value chain and it is profitable for them to do so, will choose to participate in the value chain. Default is 1 suggesting a 100% likelihood they will participate if eligible and beneficial for the smallholder.'};
            app.Image9_33.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.bValuechainparametersTab = uitab(app.TabGroup);
            app.bValuechainparametersTab.Title = '2b. Value chain parameters';
            app.Label_15 = uilabel(app.bValuechainparametersTab);
            app.Label_15.Text = 'In this section, enter the production parameters for the value chain scenario.The values represent the proportional difference in labour, capital and outputs under the value chain relative to exisitng markets. For example, a value of 1.2 for land preparation labour suggests the value chain requires 20% more labour than what is required in the existing markets. ';
            app.ValuechaincapitalinputsLabel = uilabel(app.bValuechainparametersTab);
            app.ValuechaincapitalinputsLabel.Text = 'Value chain capital inputs';
            app.ValuechainoutputsLabel = uilabel(app.bValuechainparametersTab);
            app.ValuechainoutputsLabel.Text = 'Value chain outputs';
            app.Image3 = uiimage(app.bValuechainparametersTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'inputsicon.png');
            app.Image4 = uiimage(app.bValuechainparametersTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'harvest_icon.png');
            app.FertiliserinputsEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.FertiliserinputsEditFieldLabel.Text = 'Fertiliser inputs';
            app.FertiliserinputsEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.FertiliserinputsEditField.Limits = [0 Inf];
            app.FertiliserinputsEditField.Value = 1;
            app.ChemicalinputsEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.ChemicalinputsEditFieldLabel.Text = 'Chemical inputs';
            app.ChemicalinputsEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.ChemicalinputsEditField.Limits = [0 Inf];
            app.ChemicalinputsEditField.Value = 1;
            app.SeedseedlinginputsEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.SeedseedlinginputsEditFieldLabel.Text = 'Seed/seedling inputs';
            app.SeedseedlinginputsEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.SeedseedlinginputsEditField.Limits = [0 Inf];
            app.SeedseedlinginputsEditField.Value = 1;
            app.YieldsEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.YieldsEditFieldLabel.Text = 'Yields';
            app.YieldsEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.YieldsEditField.Limits = [0 Inf];
            app.YieldsEditField.Value = 1;
            app.ConfirmvaluechainparametersButton = uibutton(app.bValuechainparametersTab, 'push');
            app.ConfirmvaluechainparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmvaluechainparametersButtonPushed, true);
            app.ConfirmvaluechainparametersButton.Icon = fullfile(pathToMLAPP, 'parametericon.png');
            app.ConfirmvaluechainparametersButton.Text = 'Confirm value chain parameters';
            app.ConfirmLamp = uilamp(app.bValuechainparametersTab);
            app.ValuechainlabourinputsLabel = uilabel(app.bValuechainparametersTab);
            app.ValuechainlabourinputsLabel.Text = 'Value chain labour inputs';
            app.Image2 = uiimage(app.bValuechainparametersTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'farmlabouricon.png');
            app.LandpreparationEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.LandpreparationEditFieldLabel.Text = 'Land preparation ';
            app.LandpreparationEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.LandpreparationEditField.Limits = [0 Inf];
            app.LandpreparationEditField.Value = 1;
            app.PlantingEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.PlantingEditFieldLabel.Text = 'Planting';
            app.PlantingEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.PlantingEditField.Limits = [0 Inf];
            app.PlantingEditField.Value = 1;
            app.WeedingEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.WeedingEditFieldLabel.Text = 'Weeding';
            app.WeedingEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.WeedingEditField.Limits = [0 Inf];
            app.WeedingEditField.Value = 1;
            app.FertiliserapplicationsEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.FertiliserapplicationsEditFieldLabel.Text = 'Fertiliser applications';
            app.FertiliserapplicationsEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.FertiliserapplicationsEditField.Limits = [0 Inf];
            app.FertiliserapplicationsEditField.Value = 1;
            app.ChemicalapplicationsEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.ChemicalapplicationsEditFieldLabel.Text = 'Chemical applications';
            app.ChemicalapplicationsEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.ChemicalapplicationsEditField.Limits = [0 Inf];
            app.ChemicalapplicationsEditField.Value = 1;
            app.HarvestingEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.HarvestingEditFieldLabel.Text = 'Harvesting';
            app.HarvestingEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.HarvestingEditField.Limits = [0 Inf];
            app.HarvestingEditField.Value = 1;
            app.PostharvesthandlingEditFieldLabel = uilabel(app.bValuechainparametersTab);
            app.PostharvesthandlingEditFieldLabel.Text = 'Post harvest handling';
            app.PostharvesthandlingEditField = uieditfield(app.bValuechainparametersTab, 'numeric');
            app.PostharvesthandlingEditField.Limits = [0 Inf];
            app.PostharvesthandlingEditField.Value = 1;
            app.ValuechaintransactioncostsTab = uitab(app.TabGroup);
            app.ValuechaintransactioncostsTab.Title = '3. Value chain transaction costs';
            app.UpfrontinvestmentcostforbuyerpervillagemillionIDREditFieldLabel = uilabel(app.ValuechaintransactioncostsTab);
            app.UpfrontinvestmentcostforbuyerpervillagemillionIDREditFieldLabel.Text = 'Upfront investment cost for buyer per village (million IDR)';
            app.UpfrontinvestmentcostforbuyerpervillagemillionIDREditField = uieditfield(app.ValuechaintransactioncostsTab, 'numeric');
            app.UpfrontinvestmentcostforbuyerpervillagemillionIDREditField.Limits = [0 Inf];
            app.UpfrontinvestmentcostforbuyerpervillagemillionIDREditField.Value = 100;
            app.EditFieldLabel_11 = uilabel(app.ValuechaintransactioncostsTab);
            app.EditFieldLabel_11.Text = 'Fixed annual transaction costs for buyer per farmer engaged in value chain (million IDR)';
            app.fixedannualfarmerforbuyerEditField = uieditfield(app.ValuechaintransactioncostsTab, 'numeric');
            app.fixedannualfarmerforbuyerEditField.Limits = [0 Inf];
            app.fixedannualfarmerforbuyerEditField.Value = 0.2;
            app.EditFieldLabel_12 = uilabel(app.ValuechaintransactioncostsTab);
            app.EditFieldLabel_12.Text = 'Fixed annual transaction costs for buyer per plot in value chain (million IDR)';
            app.fixedannualplotforbuyerEditField = uieditfield(app.ValuechaintransactioncostsTab, 'numeric');
            app.fixedannualplotforbuyerEditField.Limits = [0 Inf];
            app.fixedannualplotforbuyerEditField.Value = 0.2;
            app.EditFieldLabel_13 = uilabel(app.ValuechaintransactioncostsTab);
            app.EditFieldLabel_13.Text = 'Variable transaction costs for buyer per unit of output (million IDR)';
            app.variableforbuyerEditField = uieditfield(app.ValuechaintransactioncostsTab, 'numeric');
            app.variableforbuyerEditField.Limits = [0 Inf];
            app.EditFieldLabel_14 = uilabel(app.ValuechaintransactioncostsTab);
            app.EditFieldLabel_14.Text = 'Upfront investment cost for farmer to participate in value chain (million IDR)';
            app.UpfrontinvestmentcostforfarmermillionIDREditField = uieditfield(app.ValuechaintransactioncostsTab, 'numeric');
            app.UpfrontinvestmentcostforfarmermillionIDREditField.Limits = [0 Inf];
            app.UpfrontinvestmentcostforfarmermillionIDREditField.Value = 0.4;
            app.EditFieldLabel_15 = uilabel(app.ValuechaintransactioncostsTab);
            app.EditFieldLabel_15.Text = 'Fixed annual transaction costs for farmer to participate in value chain (million IDR)';
            app.fixedannualcostforfarmerEditField = uieditfield(app.ValuechaintransactioncostsTab, 'numeric');
            app.fixedannualcostforfarmerEditField.Limits = [0 Inf];
            app.fixedannualcostforfarmerEditField.Value = 0.12;
            app.EditFieldLabel_16 = uilabel(app.ValuechaintransactioncostsTab);
            app.EditFieldLabel_16.Text = 'Variable transaction costs for farmer per unit of output (million IDR)';
            app.variableforfarmerEditField = uieditfield(app.ValuechaintransactioncostsTab, 'numeric');
            app.variableforfarmerEditField.Limits = [0 Inf];
            app.variableforfarmerEditField.Value = 0.0005;
            app.ConfirmvaluechaintransactioncostsButton = uibutton(app.ValuechaintransactioncostsTab, 'push');
            app.ConfirmvaluechaintransactioncostsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmvaluechaintransactioncostsButtonPushed, true);
            app.ConfirmvaluechaintransactioncostsButton.Icon = fullfile(pathToMLAPP, 'parametericon.png');
            app.ConfirmvaluechaintransactioncostsButton.Text = 'Confirm value chain transaction costs';
            app.ConfirmtransactioncostsLamp = uilamp(app.ValuechaintransactioncostsTab);
            app.BuyerinvestmenttransactioncostsforvaluechainLabel = uilabel(app.ValuechaintransactioncostsTab);
            app.BuyerinvestmenttransactioncostsforvaluechainLabel.Text = 'Buyer investment/transaction costs for value chain';
            app.FarmerinvestmenttransactioncostsforvaluechainLabel = uilabel(app.ValuechaintransactioncostsTab);
            app.FarmerinvestmenttransactioncostsforvaluechainLabel.Text = 'Farmer investment/transaction costs for value chain';
            app.Label_5 = uilabel(app.ValuechaintransactioncostsTab);
            app.Label_5.Text = 'In this section, enter parameters for investment and transaction costs under the value chain. This may be for the lead firm of the value chain (e.g. collection site investments, search costs, quality assurance etc), or for each participating farmer (investments to production practices, training, paperwork).';
            app.ValuechaineligibilityTab = uitab(app.TabGroup);
            app.ValuechaineligibilityTab.Title = '4. Value chain eligibility';
            app.FlatslopedplotsCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.FlatslopedplotsCheckBox.Text = 'Flat sloped plots';
            app.FlatslopedplotsCheckBox.Value = true;
            app.ModeratelyslopedplotsCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.ModeratelyslopedplotsCheckBox.Text = 'Moderately sloped plots';
            app.ModeratelyslopedplotsCheckBox.Value = true;
            app.SteepslopedplotsCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.SteepslopedplotsCheckBox.Text = 'Steep sloped plots';
            app.SteepslopedplotsCheckBox.Value = true;
            app.RentedplotsCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.RentedplotsCheckBox.Text = 'Rented plots';
            app.RentedplotsCheckBox.Value = true;
            app.OwnermanagedplotsCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.OwnermanagedplotsCheckBox.Text = 'Owner managed plots';
            app.OwnermanagedplotsCheckBox.Value = true;
            app.PlotscurrentlywithoutsoilconservationpracticesCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.PlotscurrentlywithoutsoilconservationpracticesCheckBox.Text = 'Plots currently without soil conservation practices';
            app.PlotscurrentlywithoutsoilconservationpracticesCheckBox.Value = true;
            app.PlotscurrentlywithsoilconservationpracticesCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.PlotscurrentlywithsoilconservationpracticesCheckBox.Text = 'Plots currently with soil conservation practices';
            app.PlotscurrentlywithsoilconservationpracticesCheckBox.Value = true;
            app.PlotscurrentlyplantedwithcoffeeCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.PlotscurrentlyplantedwithcoffeeCheckBox.Text = 'Plots currently planted with coffee';
            app.PlotscurrentlyplantedwithcoffeeCheckBox.Value = true;
            app.PlotscurrentlyplantedwithhorticultureCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.PlotscurrentlyplantedwithhorticultureCheckBox.Text = 'Plots currently planted with horticulture';
            app.PlotscurrentlyplantedwithhorticultureCheckBox.Value = true;
            app.PlotscurrentlyplantedwithpaddyuplandsriceCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.PlotscurrentlyplantedwithpaddyuplandsriceCheckBox.Text = 'Plots currently planted with paddy/uplands rice';
            app.PlotscurrentlyplantedwithpaddyuplandsriceCheckBox.Value = true;
            app.PlotscurrentlyplantedwithmixedagroforestryCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.PlotscurrentlyplantedwithmixedagroforestryCheckBox.Text = 'Plots currently planted with mixed agroforestry';
            app.PlotscurrentlyplantedwithmixedagroforestryCheckBox.Value = true;
            app.MinimumeducationinyearsLabel = uilabel(app.ValuechaineligibilityTab);
            app.MinimumeducationinyearsLabel.Text = 'Minimum education (in years)    ';
            app.MinimumeducationinyearsEditField = uieditfield(app.ValuechaineligibilityTab, 'numeric');
            app.MinimumeducationinyearsEditField.Limits = [0 20];
            app.MinimumeducationinyearsEditField.AllowEmpty = 'on';
            app.MaximumdistancefrommarketinkmsEditFieldLabel = uilabel(app.ValuechaineligibilityTab);
            app.MaximumdistancefrommarketinkmsEditFieldLabel.Text = 'Maximum distance from market (in kms)';
            app.MaximumdistancefrommarketinkmsEditField = uieditfield(app.ValuechaineligibilityTab, 'numeric');
            app.MaximumdistancefrommarketinkmsEditField.Limits = [0 10];
            app.MaximumdistancefrommarketinkmsEditField.AllowEmpty = 'on';
            app.OnlyrisktalkinghouseholdsCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.OnlyrisktalkinghouseholdsCheckBox.Text = 'Only risk talking households';
            app.OnlyrisktalkinghouseholdsCheckBox.Value = true;
            app.OnlyhouseholdswhoaresociallyconnectedCheckBox = uicheckbox(app.ValuechaineligibilityTab);
            app.OnlyhouseholdswhoaresociallyconnectedCheckBox.Text = 'Only households who are socially connected';
            app.OnlyhouseholdswhoaresociallyconnectedCheckBox.Value = true;
            app.ConfirmeligibilityparametersButton = uibutton(app.ValuechaineligibilityTab, 'push');
            app.ConfirmeligibilityparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmeligibilityparametersButtonPushed2, true);
            app.ConfirmeligibilityparametersButton.Icon = fullfile(pathToMLAPP, 'parametericon.png');
            app.ConfirmeligibilityparametersButton.Text = 'Confirm eligibility parameters';
            app.eligibilityparametersconfirmedLamp = uilamp(app.ValuechaineligibilityTab);
            app.Label_6 = uilabel(app.ValuechaineligibilityTab);
            app.Label_6.Text = 'Below, value chain participation can be made eligible (ticked) or ineligible (unticked) for certain plot level and household level characteristics. Households may also need have a certain level of education or be sufficiently close to markets to be able to participate (e.g. due to paperwork or prohibitively expensive transport costs)';
            app.PlotslopeLabel = uilabel(app.ValuechaineligibilityTab);
            app.PlotslopeLabel.Text = 'Plot slope';
            app.PlottenureLabel = uilabel(app.ValuechaineligibilityTab);
            app.PlottenureLabel.Text = 'Plot tenure';
            app.PlotsoilconservationLabel = uilabel(app.ValuechaineligibilityTab);
            app.PlotsoilconservationLabel.Text = 'Plot soil conservation';
            app.CurrentlandusesLabel = uilabel(app.ValuechaineligibilityTab);
            app.CurrentlandusesLabel.Text = 'Current land uses';
            app.HouseholdcharacteristicsLabel = uilabel(app.ValuechaineligibilityTab);
            app.HouseholdcharacteristicsLabel.Text = 'Household characteristics';
            app.Image5 = uiimage(app.ValuechaineligibilityTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'ploticon.png');
            app.Image5_2 = uiimage(app.ValuechaineligibilityTab);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'tenureicon.png');
            app.Image6 = uiimage(app.ValuechaineligibilityTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'soil.png');
            app.Image6_2 = uiimage(app.ValuechaineligibilityTab);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, 'harvest_icon.png');
            app.Image6_3 = uiimage(app.ValuechaineligibilityTab);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, 'houseicon.png');
            app.Image9_24 = uiimage(app.ValuechaineligibilityTab);
            app.Image9_24.Tooltip = {'Set these to zero if you do not wish to set distance or education constraints for value chain participation.'};
            app.Image9_24.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_30 = uiimage(app.ValuechaineligibilityTab);
            app.Image9_30.Tooltip = {'Moderate sloped plots is any plot between 15 and 30% slope, while steep is any plot with a gradient above 30%'};
            app.Image9_30.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_31 = uiimage(app.ValuechaineligibilityTab);
            app.Image9_31.Tooltip = {'Respondents in the household survey indicated whether soil conservation activities were undertaken on the land they managed.These households and plots were allocated to the landscape map as part of the population generation for this agent based model. Plots with soil conservation as defined in this model and subsequent maps are not based on soil conservation activities for a given pixel on the landscape. '};
            app.Image9_31.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_32 = uiimage(app.ValuechaineligibilityTab);
            app.Image9_32.Tooltip = {'Respondents in the household survey indicated the tenure of the plots they manage. These households and plots were allocated to the landscape map as part of the population generation process for this agent based model. While the distribution of rented and owner-managed plots may resemble that of the farmer survey, rented and owned plots as defined in this model and subsequent maps are not based on actual tenure for a given pixel on the landscape. '};
            app.Image9_32.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.RunmodelTab = uitab(app.TabGroup);
            app.RunmodelTab.Title = '5. Run model';
            app.ValuechainbuyerdecisionsButton = uibutton(app.RunmodelTab, 'push');
            app.ValuechainbuyerdecisionsButton.ButtonPushedFcn = createCallbackFcn(app, @ValuechainbuyerdecisionsButtonPushed, true);
            app.ValuechainbuyerdecisionsButton.Icon = fullfile(pathToMLAPP, 'scales.png');
            app.ValuechainbuyerdecisionsButton.Text = 'Value chain buyer decisions';
            app.BuyermodelrunLamp = uilamp(app.RunmodelTab);
            app.FarmerlandusedecisionswithoutvaluechainButton = uibutton(app.RunmodelTab, 'push');
            app.FarmerlandusedecisionswithoutvaluechainButton.ButtonPushedFcn = createCallbackFcn(app, @FarmerlandusedecisionswithoutvaluechainButtonPushed, true);
            app.FarmerlandusedecisionswithoutvaluechainButton.Icon = fullfile(pathToMLAPP, 'farmlabouricon.png');
            app.FarmerlandusedecisionswithoutvaluechainButton.Text = 'Farmer land use decisions without value chain';
            app.BaselinemodelcompleteLamp = uilamp(app.RunmodelTab);
            app.FarmerlandusedecisionswithvaluechainButton = uibutton(app.RunmodelTab, 'push');
            app.FarmerlandusedecisionswithvaluechainButton.ButtonPushedFcn = createCallbackFcn(app, @FarmerlandusedecisionswithvaluechainButtonPushed, true);
            app.FarmerlandusedecisionswithvaluechainButton.Icon = fullfile(pathToMLAPP, 'farmlabouricon.png');
            app.FarmerlandusedecisionswithvaluechainButton.Text = 'Farmer land use decisions with value chain';
            app.RunpolicyluLamp = uilamp(app.RunmodelTab);
            app.PackageresultsforexportButton = uibutton(app.RunmodelTab, 'push');
            app.PackageresultsforexportButton.ButtonPushedFcn = createCallbackFcn(app, @PackageresultsforexportButtonPushed, true);
            app.PackageresultsforexportButton.Icon = fullfile(pathToMLAPP, 'export.svg');
            app.PackageresultsforexportButton.Text = 'Package results for export';
            app.DatapackagedLamp = uilamp(app.RunmodelTab);
            app.PackagedataforanalysisButton = uibutton(app.RunmodelTab, 'push');
            app.PackagedataforanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PackagedataforanalysisButtonPushed, true);
            app.PackagedataforanalysisButton.Icon = fullfile(pathToMLAPP, 'box icon.png');
            app.PackagedataforanalysisButton.Text = 'Package data for analysis';
            app.DatapreparedLamp = uilamp(app.RunmodelTab);
            app.Label_7 = uilabel(app.RunmodelTab);
            app.Label_7.Text = 'After setting up the parameters on the previous tabs, the buttons below execute the agent based model calculations. The model is run sequentially. Execute each button in order to run the model. The model is complete when all traffic lights are green. Make sure all parameters are defined in the previous sheets, indicated when all traffic lights on the previous sheets are also green.';
            app.Label_8 = uilabel(app.RunmodelTab);
            app.Label_8.Text = '1.';
            app.Label_9 = uilabel(app.RunmodelTab);
            app.Label_9.Text = '2.';
            app.Label_10 = uilabel(app.RunmodelTab);
            app.Label_10.Text = '3.';
            app.Label_11 = uilabel(app.RunmodelTab);
            app.Label_11.Text = '4.';
            app.Label_12 = uilabel(app.RunmodelTab);
            app.Label_12.Text = '5.';
            app.Image9_19 = uiimage(app.RunmodelTab);
            app.Image9_19.Tooltip = {'Packages all defined parameters for the model to be executed.'};
            app.Image9_19.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_20 = uiimage(app.RunmodelTab);
            app.Image9_20.Tooltip = {'The value chain buyer observes prices and costs and decides what villages to enter and what prices to offer farmers. '};
            app.Image9_20.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_21 = uiimage(app.RunmodelTab);
            app.Image9_21.Tooltip = {'The baseline model runs the farmer optimisation problem assuming no value chain is available to them. Farmers make land use decisions that maximise lifetime profits given the observed costs and benefits of the four land uses - coffee, horticulture, rice, and agroforestry, and the associated global parameters. '};
            app.Image9_21.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_22 = uiimage(app.RunmodelTab);
            app.Image9_22.Tooltip = {'The farmer optimisation problem is run again. Households that are eligible for value chain can opt into value chain participation if this leads to higher lifetime profits for the household.'};
            app.Image9_22.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_23 = uiimage(app.RunmodelTab);
            app.Image9_23.Tooltip = {'Package all results from the executed models ready for export. This also generates the summary results and charts of following tabs. '};
            app.Image9_23.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.SummaryofresultsTab = uitab(app.TabGroup);
            app.SummaryofresultsTab.Title = '6. Summary of results';
            app.PriceofferedtofarmersrpperunitofoutputEditFieldLabel = uilabel(app.SummaryofresultsTab);
            app.PriceofferedtofarmersrpperunitofoutputEditFieldLabel.Text = 'Price offered to farmers (rp per unit of output)';
            app.PriceofferedtofarmersrpperunitofoutputEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.PriceofferedtofarmersrpperunitofoutputEditField.Editable = 'off';
            app.PricereceivedbybuyerrpperunitofoutputEditFieldLabel = uilabel(app.SummaryofresultsTab);
            app.PricereceivedbybuyerrpperunitofoutputEditFieldLabel.Text = 'Price received by buyer (rp per unit of output)';
            app.PricereceivedbybuyerrpperunitofoutputEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.PricereceivedbybuyerrpperunitofoutputEditField.Editable = 'off';
            app.PriceofferedtofarmersrupiahperunitofoutputEditField_2Label = uilabel(app.SummaryofresultsTab);
            app.PriceofferedtofarmersrupiahperunitofoutputEditField_2Label.Text = 'Percent of farmers eligible for value chain participation';
            app.PercentoffarmerseligibleforvaluechainparticipationEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.PercentoffarmerseligibleforvaluechainparticipationEditField.Editable = 'off';
            app.EditFieldLabel_17 = uilabel(app.SummaryofresultsTab);
            app.EditFieldLabel_17.Text = 'Percent of farmers participating in value chain';
            app.PercentoffarmersparticipatinginvaluechainEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.PercentoffarmersparticipatinginvaluechainEditField.Editable = 'off';
            app.EditFieldLabel_18 = uilabel(app.SummaryofresultsTab);
            app.EditFieldLabel_18.Text = 'Average soil erosion on agricultural plots (t/hh/yr) - baseline scenario  ';
            app.BaselineaveragesoilerosiononagriculturalplotsthhyrEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.BaselineaveragesoilerosiononagriculturalplotsthhyrEditField.Editable = 'off';
            app.EditFieldLabel_19 = uilabel(app.SummaryofresultsTab);
            app.EditFieldLabel_19.Text = 'Average soil erosion on agricultural plots (t/hh/yr) - value chain intervention  ';
            app.valuechainaveragesoilerosiononagriculturalplotsthhyrEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.valuechainaveragesoilerosiononagriculturalplotsthhyrEditField.Editable = 'off';
            app.EditFieldLabel_20 = uilabel(app.SummaryofresultsTab);
            app.EditFieldLabel_20.Text = 'Average carbon biomass on agricultural plots (t/Co2) - baseline scenario  ';
            app.BaselineaveragecarbonbiomassonagriculturalplotstCo2EditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.BaselineaveragecarbonbiomassonagriculturalplotstCo2EditField.Editable = 'off';
            app.EditFieldLabel_21 = uilabel(app.SummaryofresultsTab);
            app.EditFieldLabel_21.Text = 'Average carbon biomass on agricultural plots (t/Co2) - value chain intervention  ';
            app.valuechainaveragecarbonnagriculturalplotsthhyrEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.valuechainaveragecarbonnagriculturalplotsthhyrEditField.Editable = 'off';
            app.EditFieldLabel_22 = uilabel(app.SummaryofresultsTab);
            app.EditFieldLabel_22.Text = 'Average household income (million rupiah npv over 20 years) - baseline scenario  ';
            app.BaselineaveragenpvEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.BaselineaveragenpvEditField.Editable = 'off';
            app.EditFieldLabel_23 = uilabel(app.SummaryofresultsTab);
            app.EditFieldLabel_23.Text = 'Average household income (million rupiah npv over 20 years) - value chain scenario  ';
            app.valuechainaveragenpvEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.valuechainaveragenpvEditField.Editable = 'off';
            app.Image19 = uiimage(app.SummaryofresultsTab);
            app.Image19.ImageSource = fullfile(pathToMLAPP, 'houseicon.png');
            app.Image20 = uiimage(app.SummaryofresultsTab);
            app.Image20.ImageSource = fullfile(pathToMLAPP, 'tree.png');
            app.Image21 = uiimage(app.SummaryofresultsTab);
            app.Image21.ImageSource = fullfile(pathToMLAPP, 'erosion.png');
            app.Image23 = uiimage(app.SummaryofresultsTab);
            app.Image23.ImageSource = fullfile(pathToMLAPP, 'farmlabouricon.png');
            app.Image24 = uiimage(app.SummaryofresultsTab);
            app.Image24.ImageSource = fullfile(pathToMLAPP, 'rupiah.png');
            app.SummaryofresultsLabel = uilabel(app.SummaryofresultsTab);
            app.SummaryofresultsLabel.Text = 'Summary of results';
            app.PricereceivedbybuyerrpperunitofoutputEditField_2Label = uilabel(app.SummaryofresultsTab);
            app.PricereceivedbybuyerrpperunitofoutputEditField_2Label.Text = 'Minimum price buyer needs for entry (before considering transaction costs, rp per unit of output)';
            app.MinimumpricebeforetransactioncostsEditField = uieditfield(app.SummaryofresultsTab, 'text');
            app.MinimumpricebeforetransactioncostsEditField.Editable = 'off';
            app.SummaryoflanduseTab = uitab(app.TabGroup);
            app.SummaryoflanduseTab.Title = '7. Summary of land use';
            app.UIAxes = uiaxes(app.SummaryoflanduseTab);
            xlabel(app.UIAxes, 'Plot slope')
            ylabel(app.UIAxes, 'Area of land use (Ha)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 18000];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'slight_0'; 'moderate_0'; 'steep_0'; '...                          slight_1'; 'moderate_1'; 'steep_1'};
            app.UIAxes.YTick = [0 2000 4000 6000 8000 10000 12000 14000 16000 18000];
            app.UIAxes.YTickLabel = {'0'; '2000'; '4000'; '6000'; '8000'; '10000'; '12000'; '14000'; '16000'; '18000'};
            app.LandusesummarywithoutandwithvaluechainLabel = uilabel(app.SummaryoflanduseTab);
            app.LandusesummarywithoutandwithvaluechainLabel.Text = 'Land use summary without and with value chain available to smallholders';
            app.LandscapemapsTab = uitab(app.TabGroup);
            app.LandscapemapsTab.Title = '8. Landscape maps';
            app.UIAxes2 = uiaxes(app.LandscapemapsTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.BandunglandscapemapLabel = uilabel(app.LandscapemapsTab);
            app.BandunglandscapemapLabel.Text = 'Bandung landscape map';
            app.ButtonGroup = uibuttongroup(app.LandscapemapsTab);
            app.CoffeeButton = uitogglebutton(app.ButtonGroup);
            app.CoffeeButton.Text = 'Coffee';
            app.CoffeeButton.Value = true;
            app.HorticultureButton = uitogglebutton(app.ButtonGroup);
            app.HorticultureButton.Text = 'Horticulture';
            app.RiceButton = uitogglebutton(app.ButtonGroup);
            app.RiceButton.Text = 'Rice';
            app.AgroforestryButton = uitogglebutton(app.ButtonGroup);
            app.AgroforestryButton.Text = 'Agroforestry';
            app.ValuechainButton = uitogglebutton(app.ButtonGroup);
            app.ValuechainButton.Text = 'Value chain';
            app.NotusedButton = uitogglebutton(app.ButtonGroup);
            app.NotusedButton.Text = 'Not used';
            app.PlotlanduseButton = uibutton(app.LandscapemapsTab, 'push');
            app.PlotlanduseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotlanduseButtonPushed, true);
            app.PlotlanduseButton.Text = 'Plot land use';
            app.ClearplotButton = uibutton(app.LandscapemapsTab, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearplotButton.Text = 'Clear plot';
            app.Label_16 = uilabel(app.LandscapemapsTab);
            app.Label_16.Text = 'The X and Y values represent the longitude and latitude coordinates of the pixel location projected in metres.  The black boundary represents subdistrict boundaries for Bandung and West Bandung. The red boundary represents the study area for the IndoMod model. Only plots managed by households in the model are presented on the map (white areas being a mix of other land uses and agricultural land not subject to change in the model. ';
            app.ECDFsofoutcomesTab = uitab(app.TabGroup);
            app.ECDFsofoutcomesTab.Title = '9.ECDFs of outcomes';
            app.UIAxes3 = uiaxes(app.ECDFsofoutcomesTab);
            title(app.UIAxes3, 'ECDFs')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ButtonGroup_2 = uibuttongroup(app.ECDFsofoutcomesTab);
            app.ErosionButton = uitogglebutton(app.ButtonGroup_2);
            app.ErosionButton.Text = 'Erosion';
            app.ErosionButton.Value = true;
            app.CarbonbiomassButton = uitogglebutton(app.ButtonGroup_2);
            app.CarbonbiomassButton.Text = 'Carbon biomass';
            app.ProfitsperplotButton = uitogglebutton(app.ButtonGroup_2);
            app.ProfitsperplotButton.Text = 'Profits (per plot)';
            app.ProfitsperhouseholdButton = uitogglebutton(app.ButtonGroup_2);
            app.ProfitsperhouseholdButton.Text = 'Profits (per household)';
            app.TrimlefttailLabel = uilabel(app.ButtonGroup_2);
            app.TrimlefttailLabel.Text = 'Min quantile plotted ';
            app.MinquantileplottedEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.MinquantileplottedEditField.Limits = [0 100];
            app.MinquantileplottedEditField.ValueDisplayFormat = '%0.0f%%';
            app.MaxquantileplottedLabel = uilabel(app.ButtonGroup_2);
            app.MaxquantileplottedLabel.Text = 'Max quantile plotted ';
            app.MaxquantileplottedEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.MaxquantileplottedEditField.Limits = [0 100];
            app.MaxquantileplottedEditField.ValueDisplayFormat = '%0.0f%%';
            app.MaxquantileplottedEditField.Value = 100;
            app.PlotECDFButton = uibutton(app.ECDFsofoutcomesTab, 'push');
            app.PlotECDFButton.ButtonPushedFcn = createCallbackFcn(app, @PlotECDFButtonPushed, true);
            app.PlotECDFButton.Text = 'Plot ECDF';
            app.ExportresultsTab = uitab(app.TabGroup);
            app.ExportresultsTab.Title = '10. Export results';
            app.ResultsfilepathEditFieldLabel = uilabel(app.ExportresultsTab);
            app.ResultsfilepathEditFieldLabel.Text = 'Results filepath';
            app.ResultsfilepathEditField = uieditfield(app.ExportresultsTab, 'text');
            app.PricesCheckBox = uicheckbox(app.ExportresultsTab);
            app.PricesCheckBox.Text = 'Prices';
            app.ScenarionameEditFieldLabel = uilabel(app.ExportresultsTab);
            app.ScenarionameEditFieldLabel.Text = 'Scenario name';
            app.ScenarionameEditField = uieditfield(app.ExportresultsTab, 'text');
            app.ScenarionameEditField.Placeholder = 'Scenario_1';
            app.ModelparametersLabel = uilabel(app.ExportresultsTab);
            app.ModelparametersLabel.Text = 'Model parameters';
            app.TransactioncostsCheckBox = uicheckbox(app.ExportresultsTab);
            app.TransactioncostsCheckBox.Text = 'Transaction costs';
            app.FarmereligibilityCheckBox = uicheckbox(app.ExportresultsTab);
            app.FarmereligibilityCheckBox.Text = 'Farmer eligibility';
            app.OtherparametersCheckBox = uicheckbox(app.ExportresultsTab);
            app.OtherparametersCheckBox.Text = 'Other parameters';
            app.ResultsLabel = uilabel(app.ExportresultsTab);
            app.ResultsLabel.Text = 'Results';
            app.ExportresultsButton = uibutton(app.ExportresultsTab, 'push');
            app.ExportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportresultsButtonPushed, true);
            app.ExportresultsButton.Icon = fullfile(pathToMLAPP, 'export.svg');
            app.ExportresultsButton.Text = 'Export results';
            app.ScalaradjustmentparametersCheckBox = uicheckbox(app.ExportresultsTab);
            app.ScalaradjustmentparametersCheckBox.Text = 'Scalar adjustment parameters';
            app.LanduseparametersCheckBox = uicheckbox(app.ExportresultsTab);
            app.LanduseparametersCheckBox.Text = 'Land use parameters';
            app.HouseholdcharacteristicsCheckBox = uicheckbox(app.ExportresultsTab);
            app.HouseholdcharacteristicsCheckBox.Text = 'Household characteristics';
            app.PlotcharacteristicsCheckBox = uicheckbox(app.ExportresultsTab);
            app.PlotcharacteristicsCheckBox.Text = 'Plot characteristics';
            app.LandscapecharacteristicsCheckBox = uicheckbox(app.ExportresultsTab);
            app.LandscapecharacteristicsCheckBox.Text = 'Landscape characteristics';
            app.DataLabel = uilabel(app.ExportresultsTab);
            app.DataLabel.Text = 'Data';
            app.DetailedyieldsandcostsperlanduseCheckBox = uicheckbox(app.ExportresultsTab);
            app.DetailedyieldsandcostsperlanduseCheckBox.Text = 'Detailed yields and costs per land use';
            app.LandscapelevelresultsCheckBox = uicheckbox(app.ExportresultsTab);
            app.LandscapelevelresultsCheckBox.Text = 'Landscape level results';
            app.PlotlevelresultsCheckBox = uicheckbox(app.ExportresultsTab);
            app.PlotlevelresultsCheckBox.Text = 'Plot level results';
            app.HouseholdlevelresultsCheckBox = uicheckbox(app.ExportresultsTab);
            app.HouseholdlevelresultsCheckBox.Text = 'Household level results';
            app.OtherresultsCheckBox = uicheckbox(app.ExportresultsTab);
            app.OtherresultsCheckBox.Text = 'Other results';
            app.LandusesummaryCheckBox = uicheckbox(app.ExportresultsTab);
            app.LandusesummaryCheckBox.Text = 'Land use summary';
            app.OptimallandusesummaryCheckBox = uicheckbox(app.ExportresultsTab);
            app.OptimallandusesummaryCheckBox.Text = 'Optimal land use summary';
            app.ExportcompleteLamp = uilamp(app.ExportresultsTab);
            app.HouseholdinstanceindicesCheckBox = uicheckbox(app.ExportresultsTab);
            app.HouseholdinstanceindicesCheckBox.Text = 'Household instance indices';
            app.DetailedoptimalsolutionsCheckBox = uicheckbox(app.ExportresultsTab);
            app.DetailedoptimalsolutionsCheckBox.Text = 'Detailed optimal solutions';
            app.Image9 = uiimage(app.ExportresultsTab);
            app.Image9.Tooltip = {'Parameters for each land use defined in the model, such as erosion and carbon biomass. Parameters for the value chain land use are not updated in this dataframe.'};
            app.Image9.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_2 = uiimage(app.ExportresultsTab);
            app.Image9_2.Tooltip = {'Characteristics for the 499 households that are used to generate the landscape population.'};
            app.Image9_2.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_3 = uiimage(app.ExportresultsTab);
            app.Image9_3.Tooltip = {'Start and end characteristics of plots managed by households.  '};
            app.Image9_3.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_4 = uiimage(app.ExportresultsTab);
            app.Image9_4.Tooltip = {'Characteristics for each pixel defined in the landscape. '};
            app.Image9_4.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_5 = uiimage(app.ExportresultsTab);
            app.Image9_5.Tooltip = {'Total initial hectares of coffee, horticutlure, rice, and agroforestry for smallholder plots. '};
            app.Image9_5.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_6 = uiimage(app.ExportresultsTab);
            app.Image9_6.Tooltip = {'Indexes relating the landscape population to the 499 households in the household characteristics and detailed optimal solution packages. The first column includes the household ids, and each row corresponds to a household in the landscape population that can be matched to the household level results. Columns 2 and 3 define eligibility and are saved elsewhere. '};
            app.Image9_6.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_7 = uiimage(app.ExportresultsTab);
            app.Image9_7.Tooltip = {'The prices for coffee, horticulture, rice, fruit, and harvested timber available to farmers. '};
            app.Image9_7.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_8 = uiimage(app.ExportresultsTab);
            app.Image9_8.Tooltip = {'Includes price the buyer can receive per unit of output, and all farmer and buyer transaction cost parameters.'};
            app.Image9_8.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_9 = uiimage(app.ExportresultsTab);
            app.Image9_9.Tooltip = {'Parameters defining plot and household level eligibility. Flags equal to one indicate the household/plot are eligible. For minimum education, the value indicates the minnimum education required to be eligible in years. For distance, the value indicates the maximum distance the household can be located from markets. '};
            app.Image9_9.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_10 = uiimage(app.ExportresultsTab);
            app.Image9_10.Tooltip = {'Other model parameters including discount rates, credit limits, and wage rates. '};
            app.Image9_10.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_11 = uiimage(app.ExportresultsTab);
            app.Image9_11.Tooltip = {'Adjustments made to the default capital, labour, and yield parameters for the coffee, horticulture, rice, agroforestry and value chain land uses. Only the value chain parameters can be changed in this model (Tab 2) to represent the % difference in inputs and outputs for the value chain relative to existing production practices.  Also saves preference weights which adjust the relative weight in which household value land uses for different slopes. '};
            app.Image9_11.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_12 = uiimage(app.ExportresultsTab);
            app.Image9_12.Tooltip = {'Detailed input and output flows over 20 years for each combination of starting land use, new land use, and plot slope. Saved as a .Mat file as a 5 x 5 x 3 cell structure.'};
            app.Image9_12.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_13 = uiimage(app.ExportresultsTab);
            app.Image9_13.Tooltip = {'Results include household level erosion, carbon biomass, NPV from crop production, and NPV from crop and off farm production for all instances of households on the landscape. Each instance can be mapped to the household characteristic hhids using the hhids saved in the household instance indices vector. Also includes flags to indicate whether the household is considered eligible to participate in the value chain, and whether the household participates. '};
            app.Image9_13.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_14 = uiimage(app.ExportresultsTab);
            app.Image9_14.Tooltip = {'Initial and final erosion, carbon biomass, and NPV outcomes for all plots managed by smallholder households. '};
            app.Image9_14.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_15 = uiimage(app.ExportresultsTab);
            app.Image9_15.Tooltip = {'Erosion, carbin biomass, and NPV for all map pixels'};
            app.Image9_15.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_16 = uiimage(app.ExportresultsTab);
            app.Image9_16.Tooltip = {'The minimum plot area the buyer defines for a plot to be eligible for the value chain, and the price offered to farmers per unit of output by the value chain buyer.'};
            app.Image9_16.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_17 = uiimage(app.ExportresultsTab);
            app.Image9_17.Tooltip = {'Final areas of coffee, horticulture, rice, agroforestry, the value chain, and unused agricultural land, in hectares and disaggregated by plot slope.'};
            app.Image9_17.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Image9_18 = uiimage(app.ExportresultsTab);
            app.Image9_18.Tooltip = {'Detailed linprog results for each of the 499 households, with and without the value chain offered to the household. Can be used to retrieve shadow prices. Saved as .Mat file. '};
            app.Image9_18.ImageSource = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.png');
            app.Label_13 = uilabel(app.ExportresultsTab);
            app.Label_13.Text = 'All model results may be exported from this tab for further analysis. Hover over the question marks for more details of each data file. Before exporting, specify a file path and a name for the results. All data files are saved in .csv format. ';
            app.OptionalGlobalparametersTab = uitab(app.TabGroup);
            app.OptionalGlobalparametersTab.Title = 'Optional - Global parameters';
            app.CreditlimitforestablishmentofnewlandusesEditFieldLabel = uilabel(app.OptionalGlobalparametersTab);
            app.CreditlimitforestablishmentofnewlandusesEditFieldLabel.Text = 'Credit limit for establishment of new land uses (million IDR)';
            app.CreditlimitforestablishmentofnewlandusesmillionIDREditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.CreditlimitforestablishmentofnewlandusesmillionIDREditField.Limits = [0 Inf];
            app.CreditlimitforestablishmentofnewlandusesmillionIDREditField.Value = 3;
            app.CreditlimitforoperationalphasesmillionIDREditFieldLabel = uilabel(app.OptionalGlobalparametersTab);
            app.CreditlimitforoperationalphasesmillionIDREditFieldLabel.Text = 'Credit limit for operational phases (million IDR) ';
            app.CreditlimitforoperationalphasesmillionIDREditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.CreditlimitforoperationalphasesmillionIDREditField.Limits = [0 Inf];
            app.CreditlimitforoperationalphasesmillionIDREditField.Value = 2;
            app.CommoditypriceforcoffeecherriesofferedtofarmersmillionIDRLabel = uilabel(app.OptionalGlobalparametersTab);
            app.CommoditypriceforcoffeecherriesofferedtofarmersmillionIDRLabel.Text = 'Commodity price per kg of coffee cherries offered to farmers (million IDR)';
            app.CommoditypriceforcoffeecherriesofferedtofarmersEditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.CommoditypriceforcoffeecherriesofferedtofarmersEditField.Limits = [0 Inf];
            app.CommoditypriceforcoffeecherriesofferedtofarmersEditField.Value = 0.008;
            app.CommoditypriceforcoffeecherriesofferedtofarmersEditField_2Label = uilabel(app.OptionalGlobalparametersTab);
            app.CommoditypriceforcoffeecherriesofferedtofarmersEditField_2Label.Text = 'Commodity price per kg of horticultural output offered to farmers (million IDR)';
            app.CommoditypriceforhortofferedtofarmersEditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.CommoditypriceforhortofferedtofarmersEditField.Limits = [0 Inf];
            app.CommoditypriceforhortofferedtofarmersEditField.Value = 0.003;
            app.EditFieldLabel_7 = uilabel(app.OptionalGlobalparametersTab);
            app.EditFieldLabel_7.Text = 'Commodity price per kg of rice output offered to farmers (million IDR)';
            app.CommoditypriceforriceofferedtofarmersEditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.CommoditypriceforriceofferedtofarmersEditField.Limits = [0 Inf];
            app.CommoditypriceforriceofferedtofarmersEditField.Value = 0.006;
            app.EditFieldLabel_9 = uilabel(app.OptionalGlobalparametersTab);
            app.EditFieldLabel_9.Text = 'Commodity price per kg of fruit outputs offered to farmers (million IDR)';
            app.CommoditypriceforfruitofferedtofarmersEditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.CommoditypriceforfruitofferedtofarmersEditField.Limits = [0 Inf];
            app.CommoditypriceforfruitofferedtofarmersEditField.Value = 0.0025;
            app.EditFieldLabel_10 = uilabel(app.OptionalGlobalparametersTab);
            app.EditFieldLabel_10.Text = 'Commodity price per m2 of harvested timber offered to farmers (million IDR)';
            app.CommoditypricefortimberofferedtofarmersEditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.CommoditypricefortimberofferedtofarmersEditField.Limits = [0 Inf];
            app.CommoditypricefortimberofferedtofarmersEditField.Value = 0.86682;
            app.FarmerdiscountrateEditFieldLabel = uilabel(app.OptionalGlobalparametersTab);
            app.FarmerdiscountrateEditFieldLabel.Text = 'Farmer discount rate';
            app.FarmerdiscountrateEditField = uieditfield(app.OptionalGlobalparametersTab, 'numeric');
            app.FarmerdiscountrateEditField.Limits = [0 1];
            app.FarmerdiscountrateEditField.Value = 0.15;
            app.Label_14 = uilabel(app.OptionalGlobalparametersTab);
            app.Label_14.Text = 'On this tab, global parameters may be defined that will influence the household land use decisions with and without the value chain. Here, non-value chain interventions may be tested such as introducing new credit limits, wage rates, or relative commodity price changes. ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matlab_Value_Chains

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end